classdef NWQD_Operating_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RasterScanTab                   matlab.ui.container.Tab
        Save_Position_Images            matlab.ui.control.CheckBox
        Save_Raw_Img_Raster             matlab.ui.control.CheckBox
        CurrentRowCompletion            matlab.ui.control.CheckBox
        CurrentQDPlotLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Current_Plot                    matlab.ui.control.Image
        StartStopRasterScanButton       matlab.ui.control.StateButton
        CurrentLocationofQDPanel        matlab.ui.container.Panel
        UpdateQDButton                  matlab.ui.control.Button
        QD_location                     matlab.ui.control.Label
        TextArea_RasterScan             matlab.ui.control.TextArea
        RasterScanningOvertheNanoWireChipLabel  matlab.ui.control.Label
        RegionSelectionPanel            matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        NumberOfColumns                 matlab.ui.control.NumericEditField
        NumberofColumnsLabel            matlab.ui.control.Label
        NumberOfRows                    matlab.ui.control.NumericEditField
        NumberofRowsEditField_2Label    matlab.ui.control.Label
        SpecificMovementTab             matlab.ui.container.Tab
        SpecificMovementSettingsahdChangingBigMovementPanel  matlab.ui.container.Panel
        PrecisionLockButton             matlab.ui.control.Button
        TestYStepSizeButton             matlab.ui.control.Button
        TestXStepSizeButton             matlab.ui.control.Button
        ConfirmSettingButton            matlab.ui.control.Button
        YStepNumberEditField            matlab.ui.control.NumericEditField
        YStepNumberEditFieldLabel       matlab.ui.control.Label
        XStepNumberEditField            matlab.ui.control.NumericEditField
        XStepNumberEditFieldLabel       matlab.ui.control.Label
        TextArea_RasterScan_Specific    matlab.ui.control.TextArea
        CurrentLocationofQDPanel_SpecificQD_2  matlab.ui.container.Panel
        PlotDropDown                    matlab.ui.control.DropDown
        ConfirmButton                   matlab.ui.control.Button
        COLUMNLabel                     matlab.ui.control.Label
        ROWLabel                        matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Inquired_Column                 matlab.ui.control.NumericEditField
        Inquired_Row                    matlab.ui.control.NumericEditField
        Bracket1                        matlab.ui.control.Label
        Bracket2                        matlab.ui.control.Label
        CurrentLocationofQDPanel_SpecificQD  matlab.ui.container.Panel
        UpdateQDButton_Specific         matlab.ui.control.Button
        QD_location_SpecificQD          matlab.ui.control.Label
        MovingtoSpecificQDonChipLabel   matlab.ui.control.Label
        LEDFindTab                      matlab.ui.container.Tab
        CurrentLocationofQDPanel_SpecificQD_3  matlab.ui.container.Panel
        LED_Coordinate                  matlab.ui.control.Label
        TextArea_LED                    matlab.ui.control.TextArea
        LEDCoordinateFindingProcessPanel  matlab.ui.container.Panel
        FinalImageLabel                 matlab.ui.control.Label
        FilteredImageLabel              matlab.ui.control.Label
        RawImageLabel                   matlab.ui.control.Label
        Final                           matlab.ui.control.Image
        Filtered                        matlab.ui.control.Image
        Original_Img                    matlab.ui.control.Image
        FindLEDCoordinateButton         matlab.ui.control.Button
        LEDCoordinateFinderLabel        matlab.ui.control.Label
        ManualUpdatingTab               matlab.ui.container.Tab
        CaptureCurrentPlotButton        matlab.ui.control.Button
        DebugCurrentPhotoPanel          matlab.ui.container.Panel
        Confirm_Photo_Name              matlab.ui.control.Button
        NameofRawPhotoEditField         matlab.ui.control.EditField
        QD_Photo_Debug                  matlab.ui.control.Button
        BackgroundCaptureforSpectroscopyPanel  matlab.ui.container.Panel
        CaptureBackgroundPhotosButton   matlab.ui.control.Button
        TextArea_Manual                 matlab.ui.control.TextArea
        CurrentLocationofQDPanel_SpecificQD_6  matlab.ui.container.Panel
        Confirm_LED_Manual              matlab.ui.control.Button
        YCoordinateLabel                matlab.ui.control.Label
        XCoordinateLabel                matlab.ui.control.Label
        Y_Coordinate_Manual             matlab.ui.control.NumericEditField
        X_Coordinate_Manual             matlab.ui.control.NumericEditField
        Bracket1_4                      matlab.ui.control.Label
        Bracket2_4                      matlab.ui.control.Label
        CurrentLocationofQDPanel_SpecificQD_5  matlab.ui.container.Panel
        ReverseMoveLabel                matlab.ui.control.Label
        Y_Factor_Manual_Back            matlab.ui.control.NumericEditField
        X_Factor_Manual_Back            matlab.ui.control.NumericEditField
        Bracket1_6                      matlab.ui.control.Label
        Bracket2_6                      matlab.ui.control.Label
        NumberofStepsLabel              matlab.ui.control.Label
        Num_Steps                       matlab.ui.control.NumericEditField
        Confirm_XY_Manual               matlab.ui.control.Button
        YPixelsLabel                    matlab.ui.control.Label
        XPixelsLabel                    matlab.ui.control.Label
        Y_Factor_Manual                 matlab.ui.control.NumericEditField
        X_Factor_Manual                 matlab.ui.control.NumericEditField
        Bracket1_3                      matlab.ui.control.Label
        Bracket2_3                      matlab.ui.control.Label
        CurrentLocationofQDPanel_SpecificQD_4  matlab.ui.container.Panel
        Dropdown_direction              matlab.ui.control.DropDown
        LastDirectionOverrideLabel      matlab.ui.control.Label
        Confirm_QD_Manual               matlab.ui.control.Button
        COLUMNLabel_2                   matlab.ui.control.Label
        ROWLabel_2                      matlab.ui.control.Label
        Inquired_Column_Manual          matlab.ui.control.NumericEditField
        Inquired_Row_Manual             matlab.ui.control.NumericEditField
        Bracket1_2                      matlab.ui.control.Label
        Bracket2_2                      matlab.ui.control.Label
        ManuallyUpdatedSettingsLabel    matlab.ui.control.Label
        CameraSettingsTab               matlab.ui.container.Tab
        UsercanselectLabel_2            matlab.ui.control.Label
        UsercanselectLabel              matlab.ui.control.Label
        MiddePointLineLabel             matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ROI_Middle                      matlab.ui.control.NumericEditField
        PercentagetoCoverBelowandAboveLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ROI_Percentage                  matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        StartStopASICameraButton        matlab.ui.control.StateButton
        TextBoxCameraSettings           matlab.ui.control.EditField
        SpectrometerGrattingDropDown    matlab.ui.control.DropDown
        SpectrometerGrattingDropDownLabel  matlab.ui.control.Label
        StartStopLiveSpectrumButton     matlab.ui.control.StateButton
        StartStopUICameraButton         matlab.ui.control.StateButton
        ASISpectrometerCameraSettingsPanel  matlab.ui.container.Panel
        EditField_Brightness            matlab.ui.control.NumericEditField
        EditField_Gamma                 matlab.ui.control.NumericEditField
        EditField_Gain                  matlab.ui.control.NumericEditField
        EditField_Exposure              matlab.ui.control.NumericEditField
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        GammaSlider                     matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        ExposureSecondsSlider           matlab.ui.control.Slider
        ExposureSecondsSliderLabel      matlab.ui.control.Label
        ListQDTab                       matlab.ui.container.Tab
        CurrentLocationofQDPanel_List   matlab.ui.container.Panel
        UpdateQDButton_List             matlab.ui.control.Button
        QD_location_List                matlab.ui.control.Label
        CurrentQDPlotLabel_List         matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        Current_Plot_List               matlab.ui.control.Image
        TextArea_FSS                    matlab.ui.control.TextArea
        ListofQDPanel                   matlab.ui.container.Panel
        EmptyListButton                 matlab.ui.control.Button
        UploadListButton                matlab.ui.control.Button
        ProcessDropDown                 matlab.ui.control.DropDown
        ProcessDropDownLabel            matlab.ui.control.Label
        FSSTotalAngleDropDown           matlab.ui.control.DropDown
        FSSTotalAngleDropDownLabel      matlab.ui.control.Label
        SaveRawImgDropDown              matlab.ui.control.DropDown
        SaveRawImgDropDownLabel         matlab.ui.control.Label
        CheckBox_Find_Highest_Peak      matlab.ui.control.CheckBox
        SkipBrighestSpotAroundQDFindingLabel  matlab.ui.control.Label
        CheckBox_Photon_Scan            matlab.ui.control.CheckBox
        SavePhotonCountScanFigureLabel  matlab.ui.control.Label
        List_Start_Stop                 matlab.ui.control.StateButton
        ShowListButton                  matlab.ui.control.Button
        RemoveLastQDButton              matlab.ui.control.Button
        AddToListButton                 matlab.ui.control.Button
        EditField_Column_FSS            matlab.ui.control.NumericEditField
        EditField_Row_FSS               matlab.ui.control.NumericEditField
        COLUMNLabel_3                   matlab.ui.control.Label
        ROWLabel_3                      matlab.ui.control.Label
        Bracket1_5                      matlab.ui.control.Label
        Bracket2_5                      matlab.ui.control.Label
        ANC300Tab                       matlab.ui.container.Tab
        Stop_Everything                 matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
        OKButton_Y                      matlab.ui.control.Button
        OKButton_X                      matlab.ui.control.Button
        Image8_2                        matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        Y_Mode                          matlab.ui.control.DropDown
        X_Mode                          matlab.ui.control.DropDown
        X_Volt                          matlab.ui.control.NumericEditField
        Y_Volt                          matlab.ui.control.NumericEditField
        X_Freq                          matlab.ui.control.NumericEditField
        Y_Freq                          matlab.ui.control.NumericEditField
        Image2_6                        matlab.ui.control.Image
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        Image2_5                        matlab.ui.control.Image
        ModeLabel                       matlab.ui.control.Label
        Image2_4                        matlab.ui.control.Image
        StpVLabel                       matlab.ui.control.Label
        FrqHzLabel                      matlab.ui.control.Label
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        AxisLabel                       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
         ANC300 % ANC300 serial 
         MyFuncs % Class file with all required functions 

         pyueye_initialization_return % Initilization variable for Ueye 148x Camera
         asi_initialization_return % Initilization variable for ASI 1600 sensor Camera

         % All validated column and rows 
         ValidatedInputRow
         ValidatedInputColumn 

         % All validated columns and rows for specific movement 
         Confirmed_column
         Confirmed_row

         % Validated Photo Name for debugging  
         Confirmed_Photo_name

         % Error margin for the precision locking
         Raster_scan_error_margin

         % Camera Variables
            % ASI (Spectrometer) camera variables 
             vid_ASI
             src_ASI
            % UI (Nanowire Position) camera variables 
             vid_UI
             src_UI
         % Figure for UI camera 
         UI_Fig

         % Figure for ASI Camera
         UI_Fig_ASI

         % Different colours 
         Darkgreen
         DarkRed
         DefaultColour


        % Tracking Counter
        RasterScanCounter

        % Spectrometer properties
        Spectrometer_gratting
        background_img
        wvlength
        PlotHandle
        spectrumFig;        % Handle to the spectrum figure
        spectrumAxes;       % Handle to the spectrum axes
        ImagePreview;       % Handle to the preview image


        % today's date
        date

        % QD Matrix
        QD_matrix
        
        % Save photon count value
        Save_Photon_Scan

        % All settings and data for session
        data

        % HWP rotation motor object
        ell_motor

        % SPCM initilization variable
        Photon_count_init

        % Saving Raw Imgs on Raste
        Raw_Img_Raster_Option

        % pixel movement factors
        x_factor
        y_factor
        X_Fact_Back % reverse x movement factor
        Y_Fact_Back % reverse y movement factor
        
        % Axes for ASI camera live feed
        CameraAxesASI

        % ROI region for ASI Settings
        ROI
        upperLine
        lowerLine
        ROI_Percentage_Decimal
        ROI_Middle_Pixel
        

        % Arduino Object
        Arduino

        % ANC300 Piezo settings
        Serial_Freq_Axis_X
        Serial_Freq_Axis_Y
        Serial_Volt_Axis_Y
        Serial_Volt_Axis_X
        Freq_Axis_X
        Freq_Axis_Y
        Volt_Axis_X
        Volt_Axis_Y
        Serial_Mode_Axis_X
        Serial_Mode_Axis_Y
        Mode_Axis_X
        Mode_Axis_Y


    end
    
    methods (Access = private)
        % Movement related functions 
        %----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
        function Specific_Movement_Finalized(app,InquiredQD,Process)
            

            % Parameters
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            Frequency = 20; 
            Debug_setting = "off"; % switch this to "on" to have debug or anything else to stop
            correction_parameter_QD = 100; % to specify which dot the extra step implementation starts 
            extra_correction_steps = 5; % implementing an extra step for dots specificed in the respective region afterwards
            
            % Initial Movement Towards Start
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB","");
            QD_counter = readQD_position.lastLine; 
            CurrentQD =  QD_counter;
            [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,25);
            
            if Debug_setting == "on"
                filename = sprintf("C:\\Users\\Quantum Dot\\Desktop\\Bera Yavuz - ANC300 Movement and Images\\QD_Data\\%s_Test\\Debug_File.txt",app.date);

                % Check if file exists
                fileExists = isfile(filename);

                % Open file in append mode (creates if missing)
                fileID = fopen(filename, 'a');

                % If it's the first time writing to the file, add the date at the top
                if ~fileExists
                    fprintf(fileID, 'Log File - Created on: %s\n', datestr(now, 'yyyy-mm-dd HH:MM:SS'));
                    fprintf(fileID, '---------------------------------------\n');
                end
            end

            %  Debug Line
            if Debug_setting == "on"
            fprintf(fileID,"Inquired QD: [%d %d]\n",InquiredQD); 
            fprintf(fileID,"==================================================================\n"); 
            end


            % calculating number of rows and column alongside direction needed to travel to inquired QD 
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            QD_loca_diff = CurrentQD - InquiredQD; 


            % determing the direction that will be travelled  
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            if QD_loca_diff(1) > 0 & QD_loca_diff(2) < 0
                        
            % direction for each respectiive axis 
            direction_row = "topright";
            direction_column = "bottomright"; 
            
            % Intervals for each respective axis 
            
            row_interval = [-1 0];
            column_interval = [0 1];
            
            elseif QD_loca_diff(1)  > 0 & QD_loca_diff(2) > 0 
            
            % direction for each respectiive axis 
            direction_row = "topright";
            direction_column = "topleft";
            
            % Intervals for each respective axis 
            row_interval = [-1 0];
            column_interval = [0 -1];
            
            elseif QD_loca_diff(1)  < 0 & QD_loca_diff(2) < 0 
            
            % direction for each respectiive axis 
            direction_row = "bottomleft";
            direction_column = "bottomright"; 
            
            % Intervals for each respective axis 
            row_interval = [1 0];
            column_interval = [0 1];
            
            elseif QD_loca_diff(1)  < 0 & QD_loca_diff(2) > 0 
            
            % direction for each respectiive axis     
            direction_row = "bottomleft";
            direction_column = "topleft";
            
            % Intervals for each respective axis 
            row_interval = [1 0];
            column_interval = [0 -1];
            
            elseif QD_loca_diff(1) == 0 | QD_loca_diff(2) == 0
                if QD_loca_diff(1) < 0 
                direction_row = "bottomleft";
                row_interval = [1 0];
                elseif QD_loca_diff(1) > 0 
                direction_row = "topright";
                row_interval = [-1 0];
                elseif QD_loca_diff(2) < 0 
                direction_column = "bottomright"; 
                column_interval = [0 1];
                elseif QD_loca_diff(2) > 0 
                direction_column = "topleft"; 
                column_interval = [0 -1];
                else
                    AppLoggerText = sprintf("No movement"); 
                    if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                    elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                    end
                end
                        
            end
                        
            % number of rows and columns to move 
            num_rows = abs(QD_loca_diff(1));
            num_columns = abs(QD_loca_diff(2));
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            
            
            %  Movement Towards QD  
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            dots_to_travel_row = num_rows; 
            dots_to_travel_column = num_columns;
            groups_of_dots_row = 4; 
            groups_of_dots_column = 4; 
        
            num_of_breaks_row = floor(dots_to_travel_row / groups_of_dots_row);  % How many times it travels then locks in-between each QD (along the row) 
            remaining_QD_row = mod(dots_to_travel_row, groups_of_dots_row);    % What remains that is indiviually travelled (along the row) 
            
            num_of_breaks_column = floor(dots_to_travel_column / groups_of_dots_column);  % How many times it travels then locks in-between each QD (along the column) 
            remaining_QD_column = mod(dots_to_travel_column, groups_of_dots_column);    % What remains that is indiviually travelled (along the column) 
            
            % defining the number of steps
            Y_Step_num = app.data.Y_Fast_Movement_Step; 
            X_Step_num = app.data.X_Fast_Movement_Step;
        
            
            % acceptable error margin (pixel distance from dot) for precision locking 
            fast_movement_margin = 80; 
            locking_on_margin = 40;
            final_locking_on_margin = 25; 
        
            % defining the commands depending on the direction being moved 
            row_exist  = exist('direction_row', 'var');
            column_exist = exist('direction_column', 'var');
        
            % checking existence of row to assign proper values (Fast
            % movement)
            if row_exist
                if direction_row == "bottomleft" 
                    direction_command_row = "stepu 2";
                elseif direction_row == "topright"
                    direction_command_row = "stepd 2"; 
                end
                Y_Serial_Comd = sprintf("%s %d",direction_command_row,Y_Step_num); 
           end
            
            % checking existence of column to assign proper values (fast
            % movement) 
            if column_exist
                if direction_column == "bottomright"
                    direction_command_column = "stepu 1";
                elseif direction_column == "topleft"
                    direction_command_column = "stepd 1";
                end
                X_Serial_Comd = sprintf("%s %d", direction_command_column, X_Step_num);
            end


            % fast tracking along the QDs column
            for column = 1: num_of_breaks_column
            
                % Moving to the next set of QD along column
                QD_counter = QD_counter + groups_of_dots_column*column_interval; 
                % Respective voltage and frequency values (Voltage: 1-20 V Frequency: 1-1000 Hz)
                fprintf(app.ANC300,"setv 1 30"); 
                fprintf(app.ANC300,"setf 1 30"); 
                pause(1)

                % Updating Textbox regarding movement 
                AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end

                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Fast Movement towards [%d %d]\n",QD_counter); 
                end
                
                % checking if correction factor is required 
                if QD_counter(2) > correction_parameter_QD % can get rid of this if becomes issue 
                   X_Serial_Comd = sprintf("%s %d", direction_command_column, X_Step_num + extra_correction_steps);
                end
                % fast movement towards QD 
                fprintf(app.ANC300,X_Serial_Comd);
                app.MyFuncs.step_queue_improved(app.ANC300,1,10); 
                
            
                % Making sure LED is exactly on the dot 
                fprintf(app.ANC300,"setv 1 12"); 
                fprintf(app.ANC300,"setf 1 20");
                pause(1)
                
                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Starting Semi Lock on to [%d %d]\n",QD_counter);
                end

                app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_column); 
                [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,fast_movement_margin);
                
                % Updating Textbox regarding movement 
                AppLoggerText = sprintf("Currently on [%d %d] QD",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end 

                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Situated on [%d %d]\n",QD_counter);
                end

                % Updating all visuals on GUI
                QD_text_Update(app,QD_counter)
                
            end

            % moving the remaing QD along column
            for column_remaining = 1: remaining_QD_column
                % find next point along the column 
                [~,~,~,DistanceBetweenPoints_Rotated] = app.MyFuncs.OptimizedRasterScan(StartingQD, Table_FullQDList_sorted, Rotated_Table_FullQDList_sorted,direction_column);
                
                % Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Slow Movement towards [%d %d]\n",QD_counter);
                end

                % Moving to the next QD along column
                QD_counter = QD_counter + column_interval; 
                AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end

                % Slow movement towards QD 
                app.MyFuncs.Dual_ANC300_Movement(DistanceBetweenPoints_Rotated(1),DistanceBetweenPoints_Rotated(2),direction_column,app.ANC300,Frequency,app.x_factor,app.y_factor,app.X_Fact_Back,app.Y_Fact_Back)
       
                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Starting Semi Lock on to [%d %d]\n",QD_counter);
                end

                % Making sure LED is exactly on the dot 
                app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_column); 
                [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,locking_on_margin); % trying to land on the exact dot 
                
                % Updating Textbox regarding movement
                AppLoggerText = sprintf("Currently on [%d %d] QD",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end 

                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Situated on [%d %d]\n",QD_counter);
                end
        
                % Updating all visuals on GUI
                QD_text_Update(app,QD_counter)
            end
        
            % fast tracking along the QDs row
            for row = 1:num_of_breaks_row
            
                % Moving to the next set of QD along row
                QD_counter = QD_counter + groups_of_dots_row*row_interval; 
                % Respective voltage and frequency values (Voltage: 1-20 V Frequency: 1-1000 Hz)
                fprintf(app.ANC300,"setv 2 30"); 
                fprintf(app.ANC300,"setf 2 30"); 
                pause(1)

                % Updating Textbox regarding movement
                AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end

                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Fast Movement towards [%d %d]\n",QD_counter);
                end
                
                % checking if correction factor is required 
                if QD_counter(1) > correction_parameter_QD % can get rid of this if becomes issue 
                   Y_Serial_Comd = sprintf("%s %d", direction_command_row, Y_Step_num + extra_correction_steps);
                end
                % Fast movement towards QD
                fprintf(app.ANC300,Y_Serial_Comd);
                app.MyFuncs.step_queue_improved(app.ANC300,2,10); 
            
                % Making sure LED is exactly on the dot 
                fprintf(app.ANC300,"setv 2 12"); 
                fprintf(app.ANC300,"setf 2 20"); 
                pause(1)

                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Starting Semi Lock on to [%d %d]\n",QD_counter);
                end

                app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_row); 
                [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,fast_movement_margin);
                
                % trying to land on the exact dot 
                AppLoggerText =  sprintf("Currently on [%d %d] QD",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end

                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Situated on [%d %d]\n",QD_counter);
                end
        
                % Updating all visuals on GUI
                QD_text_Update(app,QD_counter)  

            end
        
            % moving the remaing QD along row
            for row_remaining = 1: remaining_QD_row
                % find next point along the column 
                [~,~,~,DistanceBetweenPoints_Rotated] = app.MyFuncs.OptimizedRasterScan(StartingQD, Table_FullQDList_sorted, Rotated_Table_FullQDList_sorted,direction_row);
            
                 % Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Slow Movement towards [%d %d]\n",QD_counter);
                end

                % Moving to the next QD along column
                QD_counter = QD_counter + row_interval; 
                AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end

                % Slow movement towards QD 
                app.MyFuncs.Dual_ANC300_Movement(DistanceBetweenPoints_Rotated(1),DistanceBetweenPoints_Rotated(2),direction_row,app.ANC300,Frequency,app.x_factor,app.y_factor,app.X_Fact_Back,app.Y_Fact_Back)
        
                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Starting Semi Lock on to [%d %d]\n",QD_counter);
                end

                % Making sure LED is exactly on the dot 
                app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_row); 
                [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,locking_on_margin); % trying to land on the exact dot 
                
                % Updating Textbox regarding movement
                AppLoggerText = sprintf("Currently on [%d %d] QD",QD_counter);
                if Process == "List"
                    AppLogger_FSS(app,AppLoggerText,'black')
                elseif Process == "Specific"
                    AppLogger_Specific(app,AppLoggerText,'black')
                end

                %  Debug Line
                if Debug_setting == "on"
                    fprintf(fileID,"Situated on [%d %d]\n",QD_counter);
                end
        
                % Updating all visuals on GUI
                QD_text_Update(app,QD_counter)
            end
            
            % final check to make sure exactly on dot 
            
            %  Debug Line
            if Debug_setting == "on"
                fprintf(fileID,"Starting Final Precision Lock on Inquired QD [%d %d]\n",InquiredQD);
            end
             
            app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,final_locking_on_margin); % trying to land on the exact dot 
            
            % Debug line 
            if Debug_setting == "on"
                fprintf(fileID,"Final Precision Lock finished on Inquired QD [%d %d]\n",InquiredQD);
            end
            
            % Update relevant text box 
            AppLoggerText = sprintf("Arrived to inquired QD: [%d %d]",QD_counter); 
            if Process == "List"
                AppLogger_FSS(app,AppLoggerText,app.Darkgreen)
            elseif Process == "Specific"
                AppLogger_Specific(app,AppLoggerText,app.Darkgreen)
            end
            
            if Debug_setting == "on"
                fprintf(fileID,"==================================================================\n");
                
                % Close the file
                fclose(fileID);
             end
        end
        
        function Brightest_Spot_Search(app)
            % Scan for finding brightest spot on dot
            %----------------------------------------------------------------------------------------------------------------------------------------------
            AppLogger_FSS(app,"Starting Scan for brightest spot",'black'); 
            
            %Parameters
            num_steps = 120; % Number of steps for the random walk
            old_photon_count = 0; 
            highest_count = 0;
            check_after = 40; % defining number of steps to check after 
            breaking_cond = false; 
            search_percentage = 0.98; 

            % Simulate the biased random walk
            for step = 1:num_steps
                
                
                if ~breaking_cond 
                    applogger_text = sprintf("Step %d/%d",step,num_steps); % doing the initial random movement 
                    AppLogger_FSS(app,applogger_text,'black')
                else
                    applogger_text = sprintf("Step %d of search",step); 
                    AppLogger_FSS(app,applogger_text,'black')
                end

                % Randomly choose direction for x and y
                dir_x = 2*randi([0,1])-1; 
                dir_y = 2*randi([0,1])-1; 
            
                % Calculate new position
                app.MyFuncs.stochastic_movement(dir_x, dir_y, app.ANC300,Frequency);
            
                % Get the change in photon count 
                photon_counts = py.ID900_Func.query_photon_counter(app.Photon_count_init);
                
                % stop stepping since highest has been achieved 
                if photon_counts > highest_count*search_percentage & breaking_cond
                    break
                end
            
                % If intensity decreases, retrace step
                if photon_counts < old_photon_count
                    app.MyFuncs.stochastic_movement(-dir_x, -dir_y,app.ANC300,Frequency);
                end
            
                % If intensity increases accept the new position
                if photon_counts > highest_count
                    highest_count = photon_counts; % assign as new highest count
                    prev_highest_count = highest_count; % keep track of the previous highest count 
                end
                
                % after the defined number of steps check if the
                % highest count hasn't changed significantly
                if mod(step,check_after) == 0
                    if highest_count < 1.1 * prev_highest_count
                        breaking_cond = true; 
                    end 
                end

                if mod(step,check_after/2) == 0 & breaking_cond
                    search_percentage = search_percentage - 0.02; 
                end

                old_photon_count = photon_counts; 
            end

            
            AppLogger_FSS(app,"Brightest spot found",app.Darkgreen); 
        end

        % Textbox Update Functions 
        %----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
        function AppLogger_RasterScan(app,string,colour)
            app.TextArea_RasterScan.Value = [app.TextArea_RasterScan.Value;sprintf("==================================\n%s\n==================================",string)];
            app.TextArea_RasterScan.FontColor = colour;
            % Scroll to the bottom
            scroll(app.TextArea_RasterScan,'bottom')
        end

        function AppLogger_Specific(app,string,colour)
            app.TextArea_RasterScan_Specific.Value = [app.TextArea_RasterScan_Specific.Value;sprintf("==================================\n%s\n==================================",string)];
            app.TextArea_RasterScan_Specific.FontColor = colour;
            % Scroll to the bottom
            scroll(app.TextArea_RasterScan_Specific,'bottom')
        end

        function AppLogger_LED(app,string,colour)
            app.TextArea_LED.Value = [app.TextArea_LED.Value;sprintf("===================================\n%s\n===================================",string)];
            app.TextArea_LED.FontColor = colour;
            % Scroll to the bottom
            scroll(app.TextArea_LED,'bottom')
         end

        function AppLogger_Manual(app,string,colour)
            app.TextArea_Manual.Value = [app.TextArea_Manual.Value;sprintf("===================================\n%s\n===================================",string)];
            app.TextArea_Manual.FontColor = colour;
            % Scroll to the bottom
            scroll(app.TextArea_Manual,'bottom')
         end

        function AppLogger_FSS(app,string,colour)
            app.TextArea_FSS.Value = [app.TextArea_FSS.Value;sprintf("===================================\n%s\n===================================",string)];
            app.TextArea_FSS.FontColor = colour;
            % Scroll to the bottom
            scroll(app.TextArea_FSS,'bottom')
        end

        function AppLogger(app,string,colour)
            app.TextArea.Value = [app.TextArea.Value;sprintf("===================================\n%s\n===================================",string)];
            app.TextArea.FontColor = colour;
            % Scroll to the bottom
            scroll(app.TextArea_FSS,'bottom')
        end
        
        function QD_text_Update(app,QD_ID)
            % Text being used to update each QD label
            QD_Location_text = sprintf("[%d %d]",QD_ID); 
            
            % Specific QD Label
            app.QD_location_SpecificQD.Text = QD_Location_text; 
            
            % Raster Scan QD Label
            app.QD_location.Text = QD_Location_text; 

            % List QD Label
            app.QD_location_List.Text = QD_Location_text; 


        end
        % Sound Effects Functions 
        %---------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
        function Sound_Button_pressed(app)
            [y,Fs] = audioread("Button_Pressed.wav");
            sound(y,Fs)
        end

        function Check_Sound(app)
            [k,Ys] = audioread("Chew.mp3");
            sound(k,Ys)
        end
         
        function pass = BackgroundPhotoExistenceCheck(app,tab_name)
    
            % Check if background exists
            if isempty(app.background_img)
                switch tab_name
                    case "Raster"
                        AppLogger_RasterScan(app,"ERROR: Background Photo has not been captured",app.DarkRed)
                    case "Specific"
                        AppLogger_Specific(app,"ERROR: Background Photo has not been captured",app.DarkRed)
                    case "Live Spectrum"
                        app.TextBoxCameraSettings.Value = "ERROR: Background Photo has not been captured";
                        app.TextBoxCameraSettings.FontColor = app.DarkRed; 
                    case "FSS"
                        AppLogger_FSS(app,"ERROR: Background Photo has not been captured",app.DarkRed)
                end
               pass = 0;
            else
                pass = 1;
            end
            
        end
    
        function reinitialize_ASI_Camera(app,trigger_config)
    
            % Letting user know in respective textbox for update
            if trigger_config == "List"
                AppLoggerText = sprintf("ASI Camera Reinitilization has begun");
                AppLogger_FSS(app,AppLoggerText,'black')
            elseif trigger_config == "Raster"
                AppLoggerText = sprintf("ASI Camera Reinitilization has begun");
                AppLogger_RasterScan(app,AppLoggerText,'black')
            end

            % Check if ASI camera objects exist and delete them
            if ~isempty(app.vid_ASI) && isvalid(app.vid_ASI)
                flushdata(app.vid_ASI, 'all')
                stop(app.vid_ASI)
                delete(app.vid_ASI);
            end
            if ~isempty(app.src_ASI) && isvalid(app.src_ASI)
                delete(app.src_ASI);
            end
        
            % Reinitialize the ASI camera
            info = imaqhwinfo;
            if isempty(info.InstalledAdaptors)
                error('No cameras found. Connect a camera and try again.');
            end
            adaptor = info.InstalledAdaptors{1};
            camInfo = imaqhwinfo(adaptor);
        
            % Identify the ASI camera
            for i = 1:numel(camInfo.DeviceIDs)
                deviceName = camInfo.DeviceInfo(i).DeviceName;
                if contains(deviceName, "ASI")
                    ASI_Device_ID = i;
                    ASI_name = deviceName;
                    camInfo.DeviceInfo(i).DefaultFormat = 'RGB8_6248x4176'; % Set default format
                    fprintf("Reinitializing ASI camera: %s\n", ASI_name);
                    break;
                end
            end
        
            % Recreate the ASI camera objects
            app.vid_ASI = videoinput(adaptor, ASI_name, camInfo.DeviceInfo(ASI_Device_ID).DefaultFormat);
            app.src_ASI = getselectedsource(app.vid_ASI);

            % ASI Spectrometer camera capturing parameters
            app.vid_ASI.FramesPerTrigger = 3;
            app.vid_ASI.Timeout = 50; 
            triggerconfig(app.vid_ASI,'manual')
            app.vid_ASI.TriggerRepeat = 300; % Change TriggerRepeat

            if trigger_config == "immediate"
                triggerconfig(app.vid_ASI,'immediate')
                app.vid_ASI.TriggerRepeat = inf; % Change TriggerRepeat
            end


            % Reapply ASI camera settings
            proper_exposure_setting = round(log2(app.EditField_Exposure.Value)); % 2^-15 to 2^11 second
            set(app.src_ASI, "ExposureMode", "manual", "Exposure", proper_exposure_setting);
            set(app.src_ASI, "GainMode", "manual", "Gain", app.EditField_Gain.Value);
            set(app.src_ASI, "GammaMode", "manual", "Gamma", app.EditField_Gamma.Value);
            set(app.src_ASI, "Brightness", app.EditField_Brightness.Value);

            % Start Timer
            start(app.vid_ASI)
            
            % Letting user know in respective textbox for update
            AppLoggerText = sprintf("ASI Camera has finished");
            if trigger_config == "List"
                AppLogger_FSS(app,AppLoggerText,app.Darkgreen)
            elseif trigger_config == "Raster"
                AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)
            end


        end
              
   
        % Arduino Related Functions % CHECK fclose()
        % ---------------------------------------------------------------------------------------------------------------------------------------------------------------------
        function Arduino = Arduino_Init(app)
            %connect to Arduino
            %serialPort = "COM8";
            % try
            %     Arduino = serialport(serialPort,'TimeOut',1,'BaudRate',9600);% Opening serial port to arduino
            % 
            serialPort = 'COM8';
            Arduino = serial(serialPort,'TimeOut',1,'BaudRate',9600);
            try
                fopen(Arduino);      % Opening serial port to arduino
                %handles.serialConnect = serialConnect;
            catch exception
                exception; %#ok<*VUNUS>
                exception.message;
                errordlg('Motor not connected','Connection Error')
                
            end
        end

        function angle = read_encoder(app,Arduino)
            % read angle from encoder 
            flushinput(Arduino); % flush previously buffered info 
            try
                fprintf(Arduino,'%s','angle');
            catch
                error('Problem connecting to Arduino Board.');
            end
            pause(0.25);
            angle = str2num(fscanf(Arduino,'%s')); % read the current angle of the Arduino
        end
      
        function track_ardiuno_angle(app,angle)
            % Define the file name
            filename = 'C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\Scripts_&_Debugging_Tools\Ardiuno_Angles.txt';
            
            % Open the file for appending
            fid = fopen(filename, 'a+');
            
            % Check if the file opened successfully
            if fid == -1
                error('Error opening file.');
            end
            
            % Write the angle value to the file
            fprintf(fid, '%.3f\n', angle); % Writes the angle with a newline
            
            % Close the file
            fclose(fid);
        end
        
        % Live Spectrometer Functions 
        %---------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
        function previewCallback(app,obj,event,hImage)
           try
                % Get the latest frame
                Emission_Reading_Img = event.Data;
                
                % Ensure image dimensions match preview
                if size(Emission_Reading_Img) ~= size(app.ImagePreview.CData)
                    set(app.ImagePreview, 'CData', zeros(size(Emission_Reading_Img)));
                end
                
                % Convert to grayscale if needed
                if size(Emission_Reading_Img, 3) == 3
                    Emission_Reading_Img = rgb2gray(Emission_Reading_Img);
                end
                
                % Update live preview image
                set(app.ImagePreview, 'CData', Emission_Reading_Img);
                
                % Process spectrum
                height = size(Emission_Reading_Img, 1);
                central_row = app.ROI_Middle_Pixel;
                window_size = round(height * app.ROI_Percentage_Decimal);
                valid_rows = max(1, central_row - window_size) : min(height, central_row + window_size);
                
                % Compute spectrum sum and subtract background
                spectrum_sum = sum(Emission_Reading_Img(valid_rows, :), 1);
                background_Img = sum(app.background_img(valid_rows, :), 1);
                spectrum_sum = spectrum_sum - background_Img;
                
                % Update spectrum plot
                if isempty(app.PlotHandle) || ~isvalid(app.PlotHandle)
                    app.PlotHandle = plot(app.SpectrumAxes, app.wvlength, spectrum_sum, 'b');
                    xlabel(app.SpectrumAxes, 'Wavelength (nm)');
                    ylabel(app.SpectrumAxes, 'Arb. Counts');
                else
                    set(app.PlotHandle, 'YData', spectrum_sum);
                end
                
                drawnow limitrate;
                
            catch ME
                warning('Acquisition Error: %s', ME.message);
            end
        end
            
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
            % adding path way for function container and python scripts used
            addpath("C:\Users\Quantum Dot\Desktop\Bera_Yavuz_GitHub\AttoCube-Project-Stuff","C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\Scripts_&_Debugging_Tools")
        
            % adding pathway for file with images for finding excitation laser
            addpath("C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\LED_Find_Images")

            %Fetch today's folder string 
            date = py.qd_data_folder_creation.date_string(); 
            app.date = string(date);

            % Create Folders for the day 
            py.qd_data_folder_creation.create_qd_data_directories()
            
            
            % checking for the existence of a wavenlength file for the day 
            folderPath = sprintf("C:\\Users\\Quantum Dot\\Desktop\\Bera Yavuz - ANC300 Movement and Images\\QD_Data\\%s_Test",app.date);
            files = dir(fullfile(folderPath, '*.txt')); % Get all .txt files
            file_not_found = false; 
            for i = 1:length(files)
                if contains(files(i).name,"README")
                else
                    filePath = fullfile(folderPath, files(i).name); % Full path to file
                    wavelength_Data = readtable(filePath); % Read file as a matrix
                if size(wavelength_Data, 1) == 6248  % Ensure the file is the ASI Spectrum Image
                    wavelength = wavelength_Data(:, 1); % Extract the first column
                    wavelength = table2array(wavelength); 
                    app.wvlength = (wavelength)'; 
                    file_not_found = true; 
                    save("C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\Scripts_&_Debugging_Tools\Wavelength.mat","wavelength")
                    fprintf('Read wavelengths from %s\n', files(i).name);
                else
                    fprintf('Skipping %s (No valid data)\n', files(i).name);
                end
                end
                
            end

           % Check if the file exists
            if ~file_not_found
            % Display error message
            uialert(app.UIFigure, "Required Wavelength file is missing. The application will now close.", "Error", "Icon", "error");
            
            % Pause for a brief moment to allow the alert to be seen
            pause(5); 
            
            % Close the app
            delete(app);
            return
            end
            
            % fetching all previously saved settings and data 
            app.data = load("Spectrometer_Settings.mat");

            % Initilize the background image setting 
            app.background_img = app.data.background_img; 


            % adding path way for plot images 
            date_path_plots = sprintf("C:\\Users\\Quantum Dot\\Desktop\\Bera Yavuz - ANC300 Movement and Images\\QD_Data\\%s_Test\\Spectrometer_Plots",app.date);
            directoryPath_TestingImages = sprintf("C:\\Users\\Quantum Dot\\Desktop\\Bera Yavuz - ANC300 Movement and Images\\Scripts For Testing_Debugging\\Testing Images\\%s_Test",app.date); 
            addpath(date_path_plots,directoryPath_TestingImages)


            % Establishing Serial connection with ANC300
            app.ANC300 = serialport("COM7",9600);

            % Adding file containing all the need functions 
            app.MyFuncs = functionsContainer; 

            % Adding Pathway for all needed values 
            app.MyFuncs.AddPathFunc("LAB")


            % Assigning the default settings to ASI
            ASI_Settings.desired_exposure_time = 2;
            ASI_Settings.Gain = 300;
            ASI_Settings.Gamma = 50; 
            ASI_Settings.Brightness = 50; 

            % Assigning the default settings to UI
            UI_Settings = ""; % no settings as of yet due to only require basic snapping function 


            % Initilizing todays QD position tracking file (create it if doesn't exist)
            app.MyFuncs.QD_tracking_N_Identification("","","Initialize","LAB","")

            % Respective Voltage, Frequency, and Mode for each axis 
            fprintf(app.ANC300,"setm 3 gnd"); 
            fprintf(app.ANC300,"setv 1 12"); fprintf(app.ANC300,"setf 1 20"); fprintf(app.ANC300,"setm 1 stp"); 
            fprintf(app.ANC300,"setv 2 12"); fprintf(app.ANC300,"setf 2 20"); fprintf(app.ANC300,"setm 2 stp"); 

       
            % Initialize both ze cameras
            % ----------------------------------------------------------------------------------------------------------------
            [app.vid_ASI,app.src_ASI,app.vid_UI,app.src_UI] = app.MyFuncs.ASI_UI_CameraInit(ASI_Settings,UI_Settings); 
            AppLogger_RasterScan(app,"Camera has been initilized, yo - Sreesh",'black')
            pause(2)

            % Vertically Flipping the UI camera to the proper orientation   
            app.src_UI.VerticalFlip = 'on'; 

            app.src_UI.ExposureMode = "manual"; 
            app.src_UI.GainMode = "manual";
            app.src_UI.ContrastMode = "manual";
            app.vid_UI.ReturnedColorSpace = 'grayscale'; % rgb, grayscale, bayer
            
            % ASI Spectrometer camera capturing parameters
            app.vid_ASI.FramesPerTrigger = 3;
            app.vid_ASI.Timeout = 50; 
            app.vid_ASI.TriggerRepeat = 300;
            triggerconfig(app.vid_ASI,'manual')
            start(app.vid_ASI)


            % Get information on Camera's and setting sliders to default position
            % ----------------------------------------------------------------------------------------------------------------
            % Allowed range of each setting
            ASI_Exposure = propinfo(app.src_ASI,"Exposure"); min_ASI_Exposure = ASI_Exposure.ConstraintValue(1); max_ASI_Exposure = ASI_Exposure.ConstraintValue(2); 
            ASI_Gain = propinfo(app.src_ASI,"Gain"); min_ASI_Gain = ASI_Gain.ConstraintValue(1); max_ASI_Gain = ASI_Gain.ConstraintValue(2); 
            ASI_Gamma = propinfo(app.src_ASI,"Gamma"); min_ASI_Gamma = ASI_Gamma.ConstraintValue(1); max_ASI_Gamma = ASI_Gamma.ConstraintValue(2); 
            ASI_Brightness = propinfo(app.src_ASI,"Brightness"); min_ASI_Brightness = ASI_Brightness.ConstraintValue(1); max_ASI_Brightness = ASI_Brightness.ConstraintValue(2); 
          

            % Converting all values to double (Matlab's default number format)
            min_ASI_Exposure = double(min_ASI_Exposure); max_ASI_Exposure = double(max_ASI_Exposure);
            min_ASI_Gain = double(min_ASI_Gain); max_ASI_Gain = double(max_ASI_Gain);
            min_ASI_Gamma = double(min_ASI_Gamma); max_ASI_Gamma = double(max_ASI_Gamma); 
            min_ASI_Brightness = double(min_ASI_Brightness); max_ASI_Brightness = double(max_ASI_Brightness); 


            % Setting the limits of the sliders
            app.ExposureSecondsSlider.Limits = [power(2,min_ASI_Exposure) power(2,max_ASI_Exposure)];
            app.GainSlider.Limits = [min_ASI_Gain max_ASI_Gain];
            app.GammaSlider.Limits = [min_ASI_Gamma max_ASI_Gamma];
            app.BrightnessSlider.Limits = [min_ASI_Brightness max_ASI_Brightness]; 

            % Setting defaults values for each slider
            app.ExposureSecondsSlider.Value = double(ASI_Settings.desired_exposure_time);
            app.GainSlider.Value = double(ASI_Settings.Gain);
            app.GammaSlider.Value = double(ASI_Settings.Gamma);
            app.BrightnessSlider.Value = double(ASI_Settings.Brightness); 

            % Adding correct limits to boxes as well as the correct initial value assignment
            app.EditField_Exposure.Limits = [power(2,min_ASI_Exposure) power(2,max_ASI_Exposure)];       app.EditField_Exposure.Value =  ASI_Settings.desired_exposure_time;
            app.EditField_Gain.Limits = [min_ASI_Gain max_ASI_Gain];                   app.EditField_Gain.Value = ASI_Settings.Gain;
            app.EditField_Gamma.Limits = [min_ASI_Gamma max_ASI_Gamma];                app.EditField_Gamma.Value = ASI_Settings.Gamma;
            app.EditField_Brightness.Limits = [min_ASI_Brightness max_ASI_Brightness]; app.EditField_Brightness.Value = ASI_Settings.Brightness;
            
            % Initilizing Spectrometer Settings
                        % Assign values to the app properties
                        app.SpectrometerGrattingDropDown.Value = app.data.Spectrometer_gratting; app.Spectrometer_gratting = app.data.Spectrometer_gratting; 
       
            % fetching ASI ROI parameters
            app.ROI = load("ROI_Settings.mat"); 
            app.ROI_Percentage.Value = app.ROI.ROI_Screen_Percent;
            app.ROI_Middle.Value = app.ROI.ROI_Middle; 

            % Providing today's date on every text box 
            date_today_string = sprintf("Today's Date: %s",app.date); 
            AppLogger_RasterScan(app,date_today_string,[0.85 0.33 0.10])
            AppLogger_LED(app,date_today_string,[0.85 0.33 0.10])
            AppLogger_Specific(app,date_today_string,[0.85 0.33 0.10])
            AppLogger_Manual(app,date_today_string,[0.85 0.33 0.10])
            % ----------------------------------------------------------------------------------------------------------------

            % Defining Fast Movement Variables during Specific Movement Algorithm
            app.XStepNumberEditField.Value = app.data.Y_Fast_Movement_Step;
            app.YStepNumberEditField.Value = app.data.X_Fast_Movement_Step; 


            % Updating Current location of QD 
            readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB",""); 
            QD_text_Update(app,readQD_position.lastLine)

            % Updating current LED coord
            read_LED_Coordinate  = app.MyFuncs.LED_Cooordinate_Identifer("","Read","LAB"); 
            LED_coord = round(read_LED_Coordinate); 
            LED_CoordinateText = sprintf("[%d %d]",LED_coord); 
            app.LED_Coordinate.Text = LED_CoordinateText; 

            % Current number of rows and columns 
            app.NumberOfColumns.Value = 1; app.ValidatedInputColumn = 1;
            app.NumberOfRows.Value = 200; app.ValidatedInputRow = 200; 

            % Defining different colours
            app.Darkgreen = [0 0.5 0]; 
            app.DefaultColour = [.96 .96 .96]; 
            app.DarkRed = [0.8 0 0]; 

            % Tracking Counter Defining 
            app.RasterScanCounter = 0;

            % defining the value for error margins of precision locking 
            app.Raster_scan_error_margin = 35; 

            % Creating an empty matrix for the QD list
            app.QD_matrix = double.empty(0,2); 

            % Setting the default option as no, until user checks box
            % (raster scan raw img saving)
            app.Raw_Img_Raster_Option = "No";

            % X and y factors
            Read_XY_factor = app.MyFuncs.XY_Factor_Identifier("","Read","LAB");
            app.x_factor = Read_XY_factor.X_factor; %(Steps/pixels units)
            app.y_factor = Read_XY_factor.Y_factor; %(Steps/pixels units)

            % assign backwards x and y factors
            app.X_Fact_Back = app.data.X_Factor_Back;
            app.Y_Fact_Back = app.data.Y_Factor_Back; 

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.OKButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.OKButton.BackgroundColor = app.DefaultColour; 
            
            % Assigning the current value in the textbox to wanted values 
            app.ValidatedInputColumn = app.NumberOfColumns.Value;
            app.ValidatedInputRow = app.NumberOfRows.Value; 


            % Applogger text
            ApploggerTextRow = sprintf("Number of Rows selected: %d",app.ValidatedInputRow);
            ApploggerTextColumn = sprintf("Number of Columns selected: %d",app.ValidatedInputColumn);
        
            AppLogger_RasterScan(app,ApploggerTextRow,app.Darkgreen)
            AppLogger_RasterScan(app,ApploggerTextColumn,app.Darkgreen)
        end

        % Value changed function: StartStopRasterScanButton
        function StartStopRasterScanButtonValueChanged(app, event)
            app.Sound_Button_pressed()
            
            pass = BackgroundPhotoExistenceCheck(app,"Raster"); 
            if pass ~= 1
                app.StartStopRasterScanButton.Value = 0; 
                return
            end
    
            % defining all necessary parameters 
            app.RasterScanCounter = app.RasterScanCounter + 1; 
            value = app.StartStopRasterScanButton.Value; 
            Frequency = 20;
            Previous_Spec = 0;
            QD_Progress = 0; 


            % checking if the raster scan is being stopped or started
            if mod(app.RasterScanCounter,2) ~= 0
            app.StartStopRasterScanButton.BackgroundColor = 'g'; 
            AppLogger_RasterScan(app,"Raster Scan Started",app.Darkgreen)
    
            else
            app.StartStopRasterScanButton.BackgroundColor = 'r'; 
            AppLogger_RasterScan(app,"Raster Scan Stopped",app.DarkRed)
            end
            
            if value == 1
                
                % Initial Movement Towards Start
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB","");
                QD_counter = readQD_position.lastLine; 
                last_directionQD = readQD_position.thirdLastLine;
               
                [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,app.Raster_scan_error_margin);
                
                % Spectroscope Image and Plot
                [~,~,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",app.Raw_Img_Raster_Option,app.Spectrometer_gratting,QD_counter,"");
                % updating plot image 
                app.Current_Plot.ImageSource = plot_img; 
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                
                
                %  Movement Along region variables
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                num_rows = app.ValidatedInputRow;
                num_columns = app.ValidatedInputColumn;

            
                % Correction factor for QD that were not apart of main loop 
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                endPoint1 = 1;
                endPoint200 = 200; 
                CorrectionFactor = false;

               
                % check to see if value is at either end point of rasterscan
                if QD_counter(1) ~= endPoint1 && QD_counter(1) ~= endPoint200 && app.CurrentRowCompletion.Value == 1
                    % comes into play to let raster scan know that it has to move a column afterwards 
                    CorrectionFactor = true; 
                
                    if last_directionQD == "bottomleft"
                        QD_difference = abs(QD_counter(1) - endPoint200);
                        direction_QD = "bottomleft";
                        Interval = [1 0]; % row increases as it goes down 
                    elseif last_directionQD == "topright"
                        QD_difference = abs(QD_counter(1) - endPoint1);
                        direction_QD = "topright";
                        Interval = [-1 0]; % row decreases as it goes back up 
                    end
                    % for loop to move the actual difference before actual raster scan starts
                    for correctionRow = 1:QD_difference
                        QD_Progress = QD_Progress + 1; 
                        if mod(QD_Progress,75) == 0
                            reinitialize_ASI_Camera(app,"Raster")
                        end
                        % Going along the row 
                        [~,~,~,DistanceBetweenPoints_Rotated] = app.MyFuncs.OptimizedRasterScan(StartingQD, Table_FullQDList_sorted, Rotated_Table_FullQDList_sorted,direction_QD);
                
                
                        % Moving to the next QD alongrow 
                        QD_counter = QD_counter + Interval; 
                        AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                        AppLogger_RasterScan(app,AppLoggerText,'black')
                        app.MyFuncs.Dual_ANC300_Movement(DistanceBetweenPoints_Rotated(1),DistanceBetweenPoints_Rotated(2),direction_QD,app.ANC300,Frequency,app.x_factor,app.y_factor,app.X_Fact_Back,app.Y_Fact_Back)
   
                        
                        % Making sure LED is exactly on the dot 
                        app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_QD); 
                        [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,app.Raster_scan_error_margin); % trying to land on the exact dot 
                        
                        % Position Photo
                        if app.Save_Position_Images.Value
                            app.MyFuncs.UI_Snap_Img(app.vid_UI,app.src_UI,"Yes",QD_counter); 
                        end

                        % Updating textbox 
                        AppLoggerText = sprintf("Currently on [%d %d] QD",QD_counter);
                        AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)

                        % Spectroscope Image and Plot
                        [~,pks,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",app.Raw_Img_Raster_Option,app.Spectrometer_gratting,QD_counter,"");

    
                        % Updating all visuals on GUI
                        QD_text_Update(app,QD_counter)
    
                        % updating plot image 
                        app.Current_Plot.ImageSource = plot_img; 
        

                        value = app.StartStopRasterScanButton.Value; 
                        if value == 0
                            AppLoggerText = sprintf("Raster has been stopped");
                            AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)
                            return
                        end

                        % check if ASI camera crashed and is returning same
                        % img 
                        if Previous_Spec == pks
                                fprintf("correction row error\n")
                                AppLoggerText = sprintf("Identical Plots Have Been Detected\nRestart ASI Camera and Scan");
                                AppLogger_RasterScan(app,AppLoggerText,app.DarkRed)
                                return
                        end
                       
                        % Save previous peaks vals to check same image isn't being snapped
                        Previous_Spec = pks; 

    
                    end
                end
                
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------


                % Main Raster Scan Loop 
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                
                % Reading current position of QD to see which end its at ([1 _] or [200 _])
                readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB","");
                QD_counter = readQD_position.lastLine;
                row_track_factor = QD_counter;  
                
                for column = 1: num_columns
                    if column ~= 1 || CorrectionFactor == true 
                    QD_Progress = QD_Progress + 1;
                    
                    % Checking if the number of QDs has reached required amount to reinit camera
                    if mod(QD_Progress,50) == 0
                        reinitialize_ASI_Camera(app,"Raster")
                    end

                    % going along the column 
                    direction_QD = "bottomright";
                    [~,~,~,DistanceBetweenPoints_Rotated] = app.MyFuncs.OptimizedRasterScan(StartingQD, Table_FullQDList_sorted, Rotated_Table_FullQDList_sorted,direction_QD);
                
                
                    % Moving to the next QD alongrow 
                    QD_counter = QD_counter + [0 1]; 
                    AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                    AppLogger_RasterScan(app,AppLoggerText,'black')
                    app.MyFuncs.Dual_ANC300_Movement(DistanceBetweenPoints_Rotated(1),DistanceBetweenPoints_Rotated(2),direction_QD,app.ANC300,Frequency,app.x_factor,app.y_factor,app.X_Fact_Back,app.Y_Fact_Back)
                
  
                    % Making sure LED is exactly on the dot 
                    app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_QD); 
                    [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,app.Raster_scan_error_margin);
                    
                    % Position Photo
                        if app.Save_Position_Images.Value
                            app.MyFuncs.UI_Snap_Img(app.vid_UI,app.src_UI,"Yes",QD_counter); 
                        end

                    % Updating textbox
                    AppLoggerText = sprintf("Currently on [%d %d] QD",QD_counter);
                    AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)
    
                
                    % Spectroscope Image and Plot
                    [~,pks,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",app.Raw_Img_Raster_Option,app.Spectrometer_gratting,QD_counter,""); 


                    % Updating all visuals on GUI
                    QD_text_Update(app,QD_counter)
    
                    app.Current_Plot.ImageSource = plot_img; % updating plot image 
 
    
                    value = app.StartStopRasterScanButton.Value; 
                    if value == 0
                        AppLoggerText = sprintf("Scan has been stopped");
                        AppLogger_RasterScan(app,AppLoggerText,app.DarkRed)
                        return
                    end

                    % check if ASI camera crashed and is returning same img
                    if Previous_Spec == pks %it didnt print this line for some reason
                            fprintf("column error\n")
                            AppLoggerText = sprintf("Identical Plots Have Been Detected\nAttempting to Reinitilize Camera");
                            AppLogger_RasterScan(app,AppLoggerText,app.DarkRed)
                            % before reinitializing camera, check for any
                            % error messages in EventLog
                            event_check = app.vid_ASI.EventLog;
                            save("ASI_MATLAB_error_check.mat","event_check")
                            % reinitilize camera
                            reinitialize_ASI_Camera(app,"Raster")
                             % Spectroscope Image and Plot
                            [~,pks,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",app.Raw_Img_Raster_Option,app.Spectrometer_gratting,QD_counter,""); 
                            % check to see if reinitilization helped
                            
                            if Previous_Spec == pks % it just skipped to this 
                                AppLoggerText = sprintf("Identical Plots Have Been Detected\nRestart ASI Camera and Scan");
                                AppLogger_RasterScan(app,AppLoggerText,app.DarkRed)
                                return
                            end
                            app.Current_Plot.ImageSource = plot_img; % updating plot image 
                    end

                    % Save previous peaks vals to check same image isn't being snapped
                    Previous_Spec = pks; 
                
                    end
                
                    % Movement path determination alongside row 
                    % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                    % check to see if user wants to complete current row 
                    if app.CurrentRowCompletion.Value == 1

                    if mod(column,2) == 0
                        if row_track_factor(1) == 1
                            direction_QD = "topright"; 
                            Interval = [-1 0]; 
                        elseif row_track_factor(1) == 200
                            direction_QD = "bottomleft"; 
                            Interval = [1 0]; 
                        else
                                error("debug as scan did not start at correct place")
                        end
                        
                    elseif mod(column,2) ~= 0 
                        if row_track_factor(1) == 1
                            direction_QD = "bottomleft"; 
                            Interval = [1 0]; 
                        elseif row_track_factor(1) == 200
                            direction_QD = "topright"; 
                            Interval = [-1 0]; 
                        else
                                error("debug as scan did not start at correct place")
                        end
                    end

                    else
                        if mod(column,2) ~= 0
                            direction_QD = "bottomleft";
                            Interval = [1 0];
                        elseif mod(column,2) == 0
                            direction_QD = "topright"; 
                            Interval = [-1 0]; 
                        end
                    end
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                
                
                    for row = 1:num_rows-1
                        QD_Progress = QD_Progress + 1;

                        % Checking if the number of QDs has reached required amount to reinit camera
                        if mod(QD_Progress,75) == 0
                            reinitialize_ASI_Camera(app,"Raster")
                        end

                        % Going along the row 
                        [~,~,~,DistanceBetweenPoints_Rotated] = app.MyFuncs.OptimizedRasterScan(StartingQD, Table_FullQDList_sorted, Rotated_Table_FullQDList_sorted,direction_QD);
                
                        % Moving to the next QD alongrow 
                        QD_counter = QD_counter + Interval; 
                        AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                        AppLogger_RasterScan(app,AppLoggerText,'black')
                        app.MyFuncs.Dual_ANC300_Movement(DistanceBetweenPoints_Rotated(1),DistanceBetweenPoints_Rotated(2),direction_QD,app.ANC300,Frequency,app.x_factor,app.y_factor,app.X_Fact_Back,app.Y_Fact_Back)
                

                        % Making sure LED is exactly on the dot 
                        app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_QD); 
                        [StartingQD,~,Rotated_Table_FullQDList_sorted,Table_FullQDList_sorted] = app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,app.Raster_scan_error_margin);
                        
                        % Position Photo
                        if app.Save_Position_Images.Value
                            app.MyFuncs.UI_Snap_Img(app.vid_UI,app.src_UI,"Yes",QD_counter); 
                        end

                        %  Updating textbox
                        AppLoggerText = sprintf("Currently on [%d %d] QD",QD_counter);
                        AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)

                        
                       % Spectroscope Image and Plot
                       [~,pks,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",app.Raw_Img_Raster_Option,app.Spectrometer_gratting,QD_counter,"");
                       
    
                        % Updating all visuals on GUI
                        QD_text_Update(app,QD_counter)
                        
                        app.Current_Plot.ImageSource = plot_img; % Updating plot 
    
                        value = app.StartStopRasterScanButton.Value; 
                        if value == 0
                            AppLoggerText = sprintf("Close off of app Now");
                            AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)
                            return
                        end

                         % check if ASI camera crashed and is returning same img
                        if Previous_Spec == pks
                                fprintf("row error\n")
                                AppLoggerText = sprintf("Identical Plots Have Been Detected\nAttempting to Reinitilize Camera");
                                AppLogger_RasterScan(app,AppLoggerText,app.DarkRed)
                                % before reinitializing camera, check for any
                                % error messages in EventLog
                                event_check = app.vid_ASI.EventLog;
                                save("ASI_MATLAB_error_check.mat","event_check")
                                % reinitilize camera
                                reinitialize_ASI_Camera(app,"Raster")
                                 % Spectroscope Image and Plot
                                [~,pks,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",app.Raw_Img_Raster_Option,app.Spectrometer_gratting,QD_counter,""); 
                                % check to see if reinitilization helped
                                if Previous_Spec == pks
                                    AppLoggerText = sprintf("Identical Plots Have Been Detected\nRestart ASI Camera and Scan");
                                    AppLogger_RasterScan(app,AppLoggerText,app.DarkRed)
                                    return
                                end
                                app.Current_Plot.ImageSource = plot_img; % updating plot image 
                        end
    
                        % Save previous peaks vals to check same image isn't being snapped
                        Previous_Spec = pks; 


                    end
                end
                
                % Moving along to a new column once rasterscan is complete
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                QD_Progress = QD_Progress + 1; 
                % Checking if the number of QDs has reached required amount to reinit camera
                if mod(QD_Progress,50) == 0
                    reinitialize_ASI_Camera(app,"Raster")
                end

                % going along the column 
                direction_QD = "bottomright";
                [~,~,~,DistanceBetweenPoints_Rotated] = app.MyFuncs.OptimizedRasterScan(StartingQD, Table_FullQDList_sorted, Rotated_Table_FullQDList_sorted,direction_QD);
                
                % Moving to the next QD alongrow 
                QD_counter = QD_counter + [0 1]; 
                AppLoggerText = sprintf("Moving to QD [%d %d]",QD_counter);
                AppLogger_RasterScan(app,AppLoggerText,'black')
                app.MyFuncs.Dual_ANC300_Movement(DistanceBetweenPoints_Rotated(1),DistanceBetweenPoints_Rotated(2),direction_QD,app.ANC300,Frequency,app.x_factor,app.y_factor,app.X_Fact_Back,app.Y_Fact_Back)
                
                % Making sure LED is exactly on the dot 
                app.MyFuncs.QD_tracking_N_Identification(QD_counter,"Auto","Write","LAB",direction_QD); 
                app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,app.Raster_scan_error_margin);
                
                % Position Photo
                        if app.Save_Position_Images.Value
                            app.MyFuncs.UI_Snap_Img(app.vid_UI,app.src_UI,"Yes",QD_counter); 
                        end
                
                % Updating textbox
                AppLoggerText = sprintf("Currently on [%d %d] QD",QD_counter);
                AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)

                % Spectroscope Image and Plot
                [~,~,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",app.Raw_Img_Raster_Option,app.Spectrometer_gratting,QD_counter,"");
    
                % Updating all visuals on GUI
                QD_text_Update(app,QD_counter)
    
                app.Current_Plot.ImageSource = plot_img; % Updating Plot Image  

                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
                

                % Exit
                AppLoggerText = sprintf("Raster scan has been complete"); 
                AppLogger_RasterScan(app,AppLoggerText,app.Darkgreen)
                % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
    
            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.StartButton.BackgroundColor = 'g'; 
            pause(0.2)

            % disable button
            app.StartButton.Enable = "off";
            
            if app.PlotDropDown.Value == "Make Plot"
            pass = BackgroundPhotoExistenceCheck(app,"Specific"); 
                if pass ~= 1 
                    return
                end
            end
        
            
            if isempty(app.Confirmed_column) | isempty(app.Confirmed_row)
                AppLogger_Specific(app,"Inquired Row/Column Missing",'red')
                return
            end
            
            % Defining Inquired Quantum Dot (QD) 
            InquiredQD = [ app.Confirmed_row app.Confirmed_column]; 
            
            % Initiating Movement to specified QD 
            Specific_Movement_Finalized(app,InquiredQD,"Specific")
            
            % re-enable button
            app.StartButton.Enable = "on";
            app.StartButton.BackgroundColor = app.DefaultColour; 
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.ConfirmButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.ConfirmButton.BackgroundColor = app.DefaultColour; 
            
            if isempty(app.Inquired_Column.Value) | isempty(app.Inquired_Row.Value)
                AppLogger_Specific(app,"inquired Row/Column Missing",'red')
                return
            end
            
            app.Confirmed_column = app.Inquired_Column.Value; 
            app.Confirmed_row = app.Inquired_Row.Value; 
            AppLoggerText = sprintf("QD Selected: [%d %d]",app.Confirmed_row,app.Confirmed_column); 
            AppLogger_Specific(app,AppLoggerText,app.Darkgreen)

        end

        % Button pushed function: FindLEDCoordinateButton
        function FindLEDCoordinateButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.FindLEDCoordinateButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.FindLEDCoordinateButton.BackgroundColor = app.DefaultColour; 
            AppLoggerText = sprintf("LED Coord Finding In-progress"); 
            AppLogger_LED(app,AppLoggerText,'black')
           
            %Parameters 
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
          
            % Filtering Settings 
            scaling = 0.5; 
            sigma_flatfield = 60; 
            Salt_pepper_pixel_factor = [5 5]; 
            
            
            % Snapping Photo and saving it with today's date
            [UI_Position_Img] = app.MyFuncs.UI_Snap_Img(app.vid_UI,app.src_UI,"LED_Background_Debug",""); 

            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            
                     
            % Scailing Image for faster processing  
            grayImage = imresize(UI_Position_Img, scaling);
            
            % Store the original dimensions
            [originalHeight, originalWidth, ~] = size(UI_Position_Img);
            
            % Making the shadow across the image more balanced 
            FlatFieldAdjustedImg = imflatfield(grayImage,sigma_flatfield);
            
            % Getting rid of the salt-pepper noise 
            imgFiltered = medfilt2(FlatFieldAdjustedImg,Salt_pepper_pixel_factor);
            
            % Binarizing Image for simplicity 
            LED_Spot = imbinarize(imgFiltered,"adaptive","ForegroundPolarity","bright","Sensitivity",0.3);
            %LED_Spot = bwareaopen(LED_Spot,5);
            
            
            % Filtering Based on circularity 
            labelledImage = bwlabel(LED_Spot);
            propertiesImage = regionprops(labelledImage,"Circularity","Centroid","Area"); 
            AllAreas = [propertiesImage.Area]; 
            AllCircularities = [propertiesImage.Circularity];
            AllCentroids = vertcat(propertiesImage.Centroid); 
            ValidRegions = find((AllCircularities > 0.9) & AllAreas > 100);
            LED_Spot_BW = ismember(labelledImage,ValidRegions);
            LED_centroid = AllCentroids(ValidRegions,:);
            
            if isempty(LED_centroid)
                AppLoggerText = sprintf("No LED found, re-adjust accordingly"); 
                AppLogger_LED(app,AppLoggerText,'red')
                return
            end 
            % Scailing everything back up 
            LED_centroid = LED_centroid ./scaling;

            % Updating the LED coordinate text file with the new coordinate 
            if ~isempty(LED_centroid)
                 app.MyFuncs.LED_Cooordinate_Identifer(LED_centroid,"Write","LAB"); 
            LED_Spot_BW = imresize(LED_Spot_BW,[originalHeight, originalWidth]); 
            end
           
            
            GUI_orange = [0.85,0.33,0.10]; 
            visibility_setting = "off"; 
            % Plotting everything for visual purposes 
            figure("Name","OriginalImage","NumberTitle","off","Color",GUI_orange,"Visible",visibility_setting);
            imshow(UI_Position_Img)
            title("Original Image")
            exportgraphics(gcf, 'C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\LED_Find_Images\LED_Process_plot_Step1.png', 'Resolution', 300, 'BackgroundColor', GUI_orange);
            hold off; 
            
            figure("Name","First Filter","NumberTitle","off","Color",GUI_orange,"Visible",visibility_setting);
            imshow(LED_Spot)
            title("Filtered Image")
            hold on;
            plot(AllCentroids(:,1),AllCentroids(:,2),"MarkerSize",5,"MarkerEdgeColor",[1 0.5 0],"MarkerFaceColor",[1 0.5 0],"Marker","o","LineStyle","none");
            exportgraphics(gcf, 'C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\LED_Find_Images\LED_Process_plot_Step2.png', 'Resolution', 300, 'BackgroundColor', GUI_orange);
            hold off; 
            
            figure("Name","Final Filter","NumberTitle","off","Color",GUI_orange,"Visible",visibility_setting);
            title_text = sprintf("The coordinates of the excitation laser is [%.3f,%.3f]",LED_centroid(1),LED_centroid(2));
            imshow(LED_Spot_BW)
            title(title_text)
            hold on;
            plot(LED_centroid(1),LED_centroid(2),"MarkerSize",5,"MarkerEdgeColor",[1 0.5 0],"MarkerFaceColor",[1 0.5 0],"Marker","o");
            exportgraphics(gcf, 'C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\LED_Find_Images\LED_Process_plot_Final.png', 'Resolution', 300, 'BackgroundColor', GUI_orange);
            hold off; 


            % Updating all Images from LED coord finding process
            app.Original_Img.ImageSource = "LED_Process_plot_Step1.png"; 
            app.Filtered.ImageSource = "LED_Process_plot_Step2.png"; 
            app.Final.ImageSource = "LED_Process_plot_Final.png"; 

            % Updating Current LED coordinate text 
            LED_centroid = round(LED_centroid); 
            LED_coordinateText = sprintf("[%d %d]",LED_centroid); 
            app.LED_Coordinate.Text = LED_coordinateText; 

            AppLoggerText = sprintf("LED coordinate finding process has been complete"); 
            AppLogger_LED(app,AppLoggerText,app.Darkgreen)
        end

        % Button pushed function: UpdateQDButton
        function UpdateQDButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.UpdateQDButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.UpdateQDButton.BackgroundColor = app.DefaultColour; 

            % Updating Current location of QD 
            readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB",""); 
            app.QD_location.Text = mat2str(readQD_position.lastLine); 
            
            AppLogger_RasterScan(app,"Current QD updated",app.Darkgreen) 
        end

        % Button pushed function: UpdateQDButton_Specific
        function UpdateQDButton_SpecificPushed(app, event)
            app.Sound_Button_pressed()
            app.UpdateQDButton_Specific.BackgroundColor = 'g'; 
            pause(0.2)
            app.UpdateQDButton_Specific.BackgroundColor = app.DefaultColour; 

            % Updating Current location of QD 
            readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB",""); 
            app.QD_location_SpecificQD.Text = mat2str(readQD_position.lastLine); 

            AppLogger_Specific(app,"Current QD updated",app.Darkgreen) 
        end

        % Button pushed function: Confirm_QD_Manual
        function Confirm_QD_ManualButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.Confirm_QD_Manual.BackgroundColor = 'g'; 
            pause(0.2)
            app.Confirm_QD_Manual.BackgroundColor = app.DefaultColour; 

            if isempty(app.Inquired_Row_Manual.Value) | isempty(app.Inquired_Column_Manual.Value)
                AppLogger_Manual(app,"Updated direction only",'red')
                readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB",""); 
                QD_coord = readQD_position.lastLine;
                app.MyFuncs.QD_tracking_N_Identification(QD_coord,"Manual","Write","LAB",app.Dropdown_direction.Value); 
                return
            else
                QD_coord = [app.Inquired_Row_Manual.Value app.Inquired_Column_Manual.Value]; 
                app.MyFuncs.QD_tracking_N_Identification(QD_coord,"Manual","Write","LAB",app.Dropdown_direction.Value); 
                AppLogger_Manual(app,"QD_History.txt file has been updated for direction and QD",'black')
                QD_text_Update(app,QD_coord)
            end

            
        end

        % Button pushed function: Confirm_XY_Manual
        function Confirm_XY_ManualButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.Confirm_XY_Manual.BackgroundColor = 'g'; 
            pause(0.2)
            app.Confirm_XY_Manual.BackgroundColor = app.DefaultColour; 

            if isempty(app.X_Factor_Manual.Value) | isempty(app.Y_Factor_Manual.Value) | isempty(app.Num_Steps.Value) | isempty(app.X_Factor_Manual_Back.Value) | isempty(app.Y_Factor_Manual_Back.Value)
                AppLogger_Manual(app,"User has not provided a valid input",'red')
                return
            end
            numerators = app.Num_Steps.Value;
            denominators = [app.X_Factor_Manual.Value app.Y_Factor_Manual.Value];
            app.x_factor = numerators/denominators(1);
            app.y_factor = numerators/denominators(2); 
            app.X_Fact_Back = numerators/app.X_Factor_Manual_Back.Value;
            app.Y_Fact_Back = numerators/app.Y_Factor_Manual_Back.Value; 

            % Create a string array with the fraction representation
            XY_factor = string(numerators) + "/" + string(denominators);

            % Updating the XY factor text file with the new factors 
            app.MyFuncs.XY_Factor_Identifier(XY_factor,"Write","LAB"); 
            AppLogger_Manual(app,"XY_Factor_History.txt file has been updated accordingly",'black')

            % Updating mat file with back x and y factor movement 
            X_Factor_Back = app.X_Fact_Back;
            Y_Factor_Back = app.Y_Fact_Back; 
            save("Spectrometer_Settings.mat","Y_Factor_Back","X_Factor_Back","-append")

        end

        % Button pushed function: Confirm_LED_Manual
        function Confirm_LED_ManualButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.Confirm_LED_Manual.BackgroundColor = 'g'; 
            pause(0.2)
            app.Confirm_LED_Manual.BackgroundColor = app.DefaultColour; 

            if isempty(app.X_Coordinate_Manual.Value) | isempty(app.Y_Coordinate_Manual.Value) 
                AppLogger_Manual(app,"User has not provided a valid input",'red')
                return
            end
            LED_Centroid = [app.X_Coordinate_Manual.Value app.Y_Coordinate_Manual.Value]; 
            % Updating the LED coordinate text file with the new coordinate 
            app.MyFuncs.LED_Cooordinate_Identifer(LED_Centroid,"Write","LAB"); 
            AppLogger_Manual(app,"LED_Coordinate_History.txt file has been updated accordingly",'black')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                % 
                fprintf(app.ANC300,"setm 1 gnd");
                fprintf(app.ANC300,"setm 2 gnd");
                fprintf(app.ANC300,"setm 3 gnd");
                fprintf(app.ANC300,"stop 1");
                fprintf(app.ANC300,"stop 2");
                fprintf(app.ANC300,"stop 3");
                background_img = []; 
                save("Spectrometer_Settings.mat","background_img","-append")
                imaqreset
                clear app.ANC300
                delete(app)
        end

        % Value changed function: CurrentRowCompletion
        function CurrentRowCompletionValueChanged(app, event)
            value = app.CurrentRowCompletion.Value;
            
        end

        % Button pushed function: CaptureBackgroundPhotosButton
        function CaptureBackgroundPhotosButtonPushed(app, event)
            % turns tbe button green when pressed 
            app.Sound_Button_pressed()
            app.CaptureBackgroundPhotosButton.BackgroundColor = 'g'; 
            pause(0.2)
            AppLogger_Manual(app,"Background noise capture has started",'black')
            pause(0.3)
            
            % disabling button to prevent mispresses
            app.CaptureBackgroundPhotosButton.Enable = "off";
  
            [~,~,~,app.background_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Background","",app.Spectrometer_gratting,"",""); 
            AppLogger_Manual(app,"Background noise capture has been complete",app.Darkgreen)

            % renabling button after process is done
            app.CaptureBackgroundPhotosButton.Enable = "on";
            app.CaptureBackgroundPhotosButton.BackgroundColor = app.DefaultColour;  
        end

        % Button pushed function: QD_Photo_Debug
        function QD_Photo_DebugPushed(app, event)
            app.Sound_Button_pressed()
            app.QD_Photo_Debug.BackgroundColor = 'g'; 
            pause(0.2)
            app.QD_Photo_Debug.BackgroundColor = app.DefaultColour; 
            if isempty(app.Confirmed_Photo_name)
               AppLogger_Manual(app,"User has not confirmed a name",app.DarkRed)
               return
            end

            AppLogger_Manual(app,"Photo Debug capture has started",'black')
            % Snap Photo of current Position
            [UI_Position_Img] = app.MyFuncs.UI_Snap_Img(app.vid_UI,app.src_UI,"No",""); 

            % Setting the pathway to save the image to
            saving_pathway = "C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\Scripts For Testing_Debugging\Testing Images";
            specific_date = sprintf("%s_Test//%s.jpg",app.date,app.Confirmed_Photo_name); 
            full_pathway = fullfile(saving_pathway,specific_date);
            imwrite(UI_Position_Img,full_pathway);

            
            % Filtering Settings
            scaling = 0.5; 
            skyBlue = [0.53, 0.81, 0.92]; 
            radii_big_circle = 500; 
            center_big_circle = [650,500]; 
            sigma_flatfield = 35; 
            Salt_pepper_pixel_factor = [20 20]; 
            Min_circle_area = 200;
            radiusQD = 25;
            
            % LED 
            LEDSpotCentroid = app.MyFuncs.LED_Cooordinate_Identifer("","Read","LAB"); 
            LEDSpotCentroid = round(LEDSpotCentroid); 
            LEDSpotCentroidX = LEDSpotCentroid(1);
            LEDSpotCentroidY = LEDSpotCentroid(2);
            
            % Define the number of parallel lines and their separation
            num_lines = 9; % Num lines on each side of the original line
            sep_red = 345; % Separation distance red lines
            sep_blue = 370; % Seperation distance blue lines
            
            angle = 44.8; %45; %43.696474776653320; % use the angle found by the algorithm in the XYANC300Axes script 
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            
            
            % Processing raw photo for QD location data
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            [grayImage,centroid,CopyCentroid,mask,Contrast_adjusted_Img,Img,mask_scaled,MaskedImage] = app.MyFuncs.Finalized_Analyzed_QDBinaryImg_With_Dots(scaling,radii_big_circle,center_big_circle,sigma_flatfield,Salt_pepper_pixel_factor,Min_circle_area,UI_Position_Img);
            [allNextPts,allPerpPts] = app.MyFuncs.Finalized_MainAxes(Img,CopyCentroid,radiusQD);
            [x_main,y,x_perp,y_perp,b,perp_b,m,perp_m] = app.MyFuncs.Finalized_GridLines(allNextPts,allPerpPts); 
            [VirtualQDList,FullQDList_sorted,AllPossibleQDList,RealQDCentroids,centroidx,centroidy] = app.MyFuncs.Finalized_VirtualQD(num_lines,sep_red,sep_blue,m,perp_m,b,perp_b,CopyCentroid,Img,mask_scaled); 
            [VirtualQDList_rotated,RealQD_rotated,LEDSpotCentroid_rotated,Table_FullQDList_sorted,Rotated_Table_FullQDList_sorted,rotated_image] = app.MyFuncs.Rotated_Img_n_Pts(angle,MaskedImage,FullQDList_sorted,VirtualQDList,RealQDCentroids,LEDSpotCentroid);
            
            % PLotting Original Photo
            % -----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            figure_title = sprintf("LED spot highlighted On Original Image: %s",UI_Position_Img); 
            figure("Name",figure_title,"NumberTitle","off","Color",skyBlue)
            imshow(MaskedImage)
            hold on; 
            
            % Plot Virtual QD
            plot(VirtualQDList(:,1), VirtualQDList(:,2), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r',"LineStyle","none");
            
            % Plot Real QD 
            plot(centroidx, centroidy, 'go', 'MarkerSize', 7, 'MarkerFaceColor', 'g',"LineStyle","none");
            
            % Plot LED 
            plot(LEDSpotCentroidX,LEDSpotCentroidY,"MarkerSize",5,"MarkerEdgeColor",[1 0.5 0],"MarkerFaceColor",[1 0.5 0],"Marker","o")
            hold off; 
            % -----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            % Plotting rotated image 
            figure_title = sprintf("Virtual and Real QD rotated: %s",UI_Position_Img); 
            figure("Name",figure_title,"NumberTitle","off","Color",skyBlue)
            imshow(rotated_image);
            hold on;
            
            % Plot Virtual Roated QD 
            plot(VirtualQDList_rotated(:,1), VirtualQDList_rotated(:,2), 'ro', 'MarkerSize', 7, 'MarkerFaceColor', 'r',"LineStyle","none");
            
            % Plot Real Rotated QD 
            plot(RealQD_rotated(:,1), RealQD_rotated(:,2), 'go', 'MarkerSize', 7, 'MarkerFaceColor', 'g',"LineStyle","none");
            
            % Plot Rotated LED 
            plot(LEDSpotCentroid_rotated(1),LEDSpotCentroid_rotated(2),"MarkerSize",5,"MarkerEdgeColor",[1 0.5 0],"MarkerFaceColor",[1 0.5 0],"Marker","o")
            
            title("All Real and Virtual QD Rotated")
            AppLogger_Manual(app,"Photo Debug has finished",app.Darkgreen)
            hold off; 
        end

        % Button pushed function: Confirm_Photo_Name
        function Confirm_Photo_NameButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.Confirm_Photo_Name.BackgroundColor = 'g'; 
            pause(0.2)
            app.Confirm_Photo_Name.BackgroundColor = app.DefaultColour; 

            if isempty(app.NameofRawPhotoEditField.Value) 
                AppLogger_Manual(app,"User has not provided a name for file",'red')
                return
            end

            app.Confirmed_Photo_name = app.NameofRawPhotoEditField.Value; 
        end

        % Value changed function: ExposureSecondsSlider
        function ExposureSecondsSliderValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.ExposureSecondsSlider.Value;
            % Updating the appropriate edit field
            app.EditField_Exposure.Value = value;
            value = round(log2(value)); 

            % Updating the actual camera setting 
            app.src_ASI.Exposure = value; 
            start(app.vid_ASI)
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.GainSlider.Value;
            % Updating the appropriate edit field
            app.EditField_Gain.Value = value;

            % Updating the actual camera setting 
            app.src_ASI.Gain = value; 
            start(app.vid_ASI)
        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.GammaSlider.Value;
            % Updating the appropriate edit field
            app.EditField_Gamma.Value = value;

            % Updating the actual camera setting 
            app.src_ASI.Gamma = value; 
            start(app.vid_ASI)
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.BrightnessSlider.Value;
             % Updating the appropriate edit field
            app.EditField_Brightness.Value = value;

            % Updating the actual camera setting 
            app.src_ASI.Brightness = value; 
            start(app.vid_ASI)
        end

        % Value changed function: EditField_Exposure
        function EditField_ExposureValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.EditField_Exposure.Value;
            % Updating the appropriate slider
            app.ExposureSecondsSlider.Value = value;

            % Updating the actual camera setting 
            value = round(log2(value));
            app.src_ASI.Exposure = value;
            start(app.vid_ASI)
        end

        % Value changed function: EditField_Gain
        function EditField_GainValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.EditField_Gain.Value;
            % Updating the appropriate slider
            app.GainSlider.Value = value;

            % Updating the actual camera setting 
            app.src_ASI.Gain = value;
            start(app.vid_ASI)
        end

        % Value changed function: EditField_Gamma
        function EditField_GammaValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.EditField_Gamma.Value;
            % Updating the appropriate slider
            app.GammaSlider.Value = value;

            % Updating the actual camera setting 
            app.src_ASI.Gamma = value;
            start(app.vid_ASI)
        end

        % Value changed function: EditField_Brightness
        function EditField_BrightnessValueChanged(app, event)
            stop(app.vid_ASI)
            value = app.EditField_Brightness.Value;
            % Updating the appropriate slider
            app.BrightnessSlider.Value = value;

            % Updating the actual camera setting 
            app.src_ASI.Brightness = value;
            start(app.vid_ASI)
        end

        % Value changed function: SpectrometerGrattingDropDown
        function SpectrometerGrattingDropDownValueChanged(app, event)
            value = app.SpectrometerGrattingDropDown.Value;
            % updating mat file that saves all changes
            app.Spectrometer_gratting = value; 
            Spectrometer_gratting = value;
            save("Spectrometer_Settings.mat","Spectrometer_gratting","-append")
        end

        % Value changed function: StartStopLiveSpectrumButton
        function StartStopLiveSpectrumButtonValueChanged(app, event)
        % Load background average (if not already loaded)
        if ~isfield(app, 'background_img')
            app.background_img = app.data.background_img;
        end
        
        % % check if background photos have been snapped 
        % pass = BackgroundPhotoExistenceCheck(app,"Live Spectrum");
        % if pass ~= 1
        %     app.StartStopLiveSpectrumButton.Value = 0; 
        %     return
        % end
        

        if app.StartStopLiveSpectrumButton.Value  % Button is toggled on
            app.StartStopLiveSpectrumButton.Enable = "off";
            app.Sound_Button_pressed()
            app.StartStopLiveSpectrumButton.BackgroundColor = 'g'; 
            % Create the figure and axes for the live spectrum display
            screenSize = get(0, 'ScreenSize'); % gets pixel resolution of screen
            dimensions_ASI = app.vid_ASI.VideoResolution; % Get the resolution of the video input
            width = dimensions_ASI(1); % Video width
            height = dimensions_ASI(2); % Video height

            % Shrink the figure to a fraction of the original resolution
            figWidth = round(width / 8);
            figHeight = round(height / 8);

            % Position the window on the screen (shifted right and centered vertically)
            left = (screenSize(3) - figWidth) * 0.75; 
            bottom = (screenSize(4) - figHeight) / 2;

            % Create a figure with specified dimensions and position
            app.spectrumFig = figure('Name', 'Live Spectrum Display','Units', 'pixels', 'Position', [left, bottom, figWidth, figHeight], "NumberTitle", "off",'WindowStyle', 'modal');

            % Create axes within the figure (slightly smaller for padding)
            app.spectrumAxes = axes('Units', 'pixels', 'Position', [10, 10, figWidth - 20, figHeight - 20]);
      
            app.PlotHandle = plot(app.spectrumAxes, nan, nan); % Initialize with empty data
            xlabel(app.spectrumAxes, 'Wavelength (nm)');
            ylabel(app.spectrumAxes, 'Arb. Counts');

            % set to new camera settings 
            reinitialize_ASI_Camera(app,"immediate")
            
        
            % Create the image object for preview
            resolution = app.vid_ASI.VideoResolution;
            nbands = app.vid_ASI.NumberOfBands; 
            app.ImagePreview = image(app.spectrumAxes,zeros(resolution(2), resolution(1), nbands));
            
            % Set the callback function for updating the preview window
            setappdata(app.ImagePreview,'UpdatePreviewWindowFcn',@(obj,event,hImage) previewCallback(app,obj,event,hImage));
            
            % Start the preview
            preview(app.vid_ASI, app.ImagePreview);
            app.StartStopLiveSpectrumButton.Enable = "on";
            pause(2)
        else % Button is toggled off
            % Stop the preview and clean up
            app.StartStopLiveSpectrumButton.Enable = "off";
            app.Sound_Button_pressed()
            app.StartStopLiveSpectrumButton.BackgroundColor = app.DefaultColour;  
            
            % set camera settings back to the old version
            reinitialize_ASI_Camera(app,"")

            if ~isempty(app.vid_ASI) && isvalid(app.vid_ASI)
                stoppreview(app.vid_ASI);
            end
            
            % Close the spectrum figure if it exists
            if ~isempty(app.spectrumFig) && ishandle(app.spectrumFig)
                close(app.spectrumFig);
                app.spectrumFig = []; % Clear the figure handle
            end
            app.StartStopLiveSpectrumButton.Enable = "on";
            pause(2)
        end
 
        end

        % Value changed function: StartStopUICameraButton
        function StartStopUICameraButtonValueChanged(app, event)
            value = app.StartStopUICameraButton.Value;

            if value
                app.Sound_Button_pressed()
                app.StartStopUICameraButton.BackgroundColor = 'g'; 
                % Assuming vid_UI is correctly initialized
                screenSize = get(0, 'ScreenSize'); % gets pixel resolution of screen
                dimensions_UI = app.vid_UI.VideoResolution; % Get the resolution of the video input
                width = dimensions_UI(1); % Video width
                height = dimensions_UI(2); % Video height
                
                % Shrink the figure to a fraction of the original resolution
                figWidth = round(width / 3.5);
                figHeight = round(height / 3.5);
                
                % Position the window on the screen (shifted right and centered vertically)
                left = (screenSize(3) - figWidth) * 0.75; 
                bottom = (screenSize(4) - figHeight) / 2;
                
                % Create a figure with specified dimensions and position
                app.UI_Fig = figure('Units', 'pixels', 'Position', [left, bottom, figWidth, figHeight],"NumberTitle","off");
                
              
                % Create axes within the figure (slightly smaller for padding)
                axes('Units', 'pixels', 'Position', [10, 10, figWidth - 20, figHeight - 20]);
                
                % Get the video resolution and number of bands (for RGB or grayscale)
                vidRes = get(app.vid_UI, 'VideoResolution');
                nBands = get(app.vid_UI, 'NumberOfBands');
                
                % Initialize the image handle in the specified axes, with the same resolution as the video
                hImage = image(zeros(vidRes(2), vidRes(1), nBands));
                % disp(class(app.UI_Fig))
                
                % Start the preview on the video input object, passing the axes handle for display
                preview(app.vid_UI, hImage); 
            else
                app.Sound_Button_pressed()
                app.StartStopUICameraButton.BackgroundColor = app.DefaultColour;
                closepreview(app.vid_UI);
                if isvalid(app.UI_Fig)
                    close(app.UI_Fig)
                end
            end
        end

        % Button pushed function: AddToListButton
        function AddToListButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.AddToListButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.AddToListButton.BackgroundColor = app.DefaultColour; 

            QD_ID = [app.EditField_Row_FSS.Value app.EditField_Column_FSS.Value]; 
            if ~isempty(app.EditField_Row_FSS.Value) && ~isempty(app.EditField_Column_FSS.Value) && ~ismember(QD_ID,app.QD_matrix,"rows")
                app.QD_matrix = [app.QD_matrix; QD_ID];
                % succesfully adding to list message
                success_msg = sprintf("QD: [%d %d] has been succesfully added to the list",QD_ID);
                AppLogger_FSS(app,success_msg,app.Darkgreen)

            elseif ismember(QD_ID,app.QD_matrix,"rows")
                error_msg = sprintf("QD trying to be added already exists");
                AppLogger_FSS(app,error_msg,app.DarkRed)
            else
                error_msg = sprintf("User has not inputted a Row or Column number");
                AppLogger_FSS(app,error_msg,app.DarkRed)
            end


        end

        % Button pushed function: RemoveLastQDButton
        function RemoveLastQDButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.RemoveLastQDButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.RemoveLastQDButton.BackgroundColor = app.DefaultColour; 

            if isempty(app.QD_matrix)
            error_msg = sprintf("List is empty, no QD to remove"); 
            AppLogger_FSS(app,error_msg,app.DarkRed)
            else
            last_row_QD = app.QD_matrix(end,:); 
            app.QD_matrix(end,:) = []; 
            success_msg = sprintf("QD: [%d %d] has been succesfully removed from the list",last_row_QD);
            AppLogger_FSS(app,success_msg,'black')
            end


        end

        % Button pushed function: ShowListButton
        function ShowListButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.ShowListButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.ShowListButton.BackgroundColor = app.DefaultColour;  
            
            if isempty(app.QD_matrix)
            error_msg = sprintf("List is empty, no QD to show"); 
            AppLogger_FSS(app,error_msg,app.DarkRed)
                return
            end
           
            % Initialize an empty string
            outputStr = "";
            
            % Loop through each row of the matrix
            for i = 1:size(app.QD_matrix, 1)
                outputStr = sprintf('%s[%d %d]\n', outputStr, app.QD_matrix(i, 1), app.QD_matrix(i, 2));
            end

            % Creating and updating final outputted list
            outputStr = outputStr(1:end-1);
            List_msg = sprintf("Current List of QD:\n%s",outputStr); 
            % Text area updating 
            AppLogger_FSS(app,List_msg,'black')

        end

        % Value changed function: List_Start_Stop
        function List_Start_StopPushed(app, event)
            % Checks to see if user captured a background noise for current run
            pass = BackgroundPhotoExistenceCheck(app,"FSS"); 
            if pass ~= 1
                return
            end
            
            % Controls color of button to notify user about current process
            if app.List_Start_Stop.Value
                app.List_Start_Stop.BackgroundColor = 'g'; 
                AppLogger_FSS(app,"List Scan Started",app.Darkgreen)
            else
                app.List_Start_Stop.BackgroundColor = 'r'; 
                AppLogger_FSS(app,"List Scan Stopped",app.DarkRed)
                return; % Exit if button is not pressed
            end
            
            % initilizes photon counter if the variable doesn't exist 
            if isempty(app.Photon_count_init) & app.ProcessDropDown.Value == "FSS"
                try
                    app.Photon_count_init = py.ID900_Func.init_ID900();
                    AppLogger_FSS(app,"SPCM has been initialized",'black')
                catch ME
                    AppLogger_FSS(app, sprintf("Error: %s", ME.message), app.DarkRed);
                    return
                end
            end
            
            % initilizes HWP motor if the variable doesn't exist
            if isempty(app.ell_motor)  & app.ProcessDropDown.Value == "FSS"
                try
                    app.ell_motor = serialport("COM10",9600);
                    AppLogger_FSS(app,"Rotation motor has been initialized",'black')
                catch ME
                    AppLogger_FSS(app, sprintf("Error: %s", ME.message), app.DarkRed);
                    return
                end

            end
            
            % checking that user has inputed values to the list 
            if isempty(app.QD_matrix)
                error_msg = sprintf("List is empty, no QD to show"); 
                AppLogger_FSS(app,error_msg,app.DarkRed)
                return
            end


            % Defining required parameters 
            % ---------------------------------------------------------------------
            QD_Progress = 0; %variable for tracking current number of QD done
            num_QDs = size(app.QD_matrix,1);   % get number of QD in list
            Process_Type = string(app.ProcessDropDown.Value); 
            previous_pks = 0; 

            for QD_Scanned = 1:num_QDs
                

                % reinitilizing spectrometer camera after a given number of QD
                QD_Progress = QD_Progress + 1; 
                if mod(QD_Progress,30) == 0
                    reinitialize_ASI_Camera(app,"List")
                end

  
                % Initiating Movement to specified QD 
                InquiredQD = app.QD_matrix(QD_Scanned,:); 
                Specific_Movement_Finalized(app,InquiredQD,"List")
                % app.QD_matrix(QD_Scanned,:) = [];  % consider it and ask
                % sreesh first 

                % Creating folder for respective QD scan
                app.MyFuncs.FSS_Folder_Creation(InquiredQD,Process_Type)

                if contains(Process_Type,"Emission")
                    % Spectroscope Image and Plot
                    [~,pks,plot_img] = app.MyFuncs.ASI_Snap_Img(app.vid_ASI,app.src_ASI,"Spectrometer",convertCharsToStrings(app.SaveRawImgDropDown.Value),app.Spectrometer_gratting,InquiredQD,"Emission");

                    % updating plot image 
                    app.Current_Plot_List.ImageSource = plot_img; 

                    % checking same photo wasn't captured 
                    if pks == previous_pks
                        AppLogger_FSS(app,"Identical peaks detected stopping code",app.DarkRed);
                        return
                    end
                    previous_pks = pks; 
                end

            if ~app.List_Start_Stop.Value
                break; % Exit the loop if the button is unpressed
            end
    
                % FSS Process if user selected it 
                if contains(Process_Type,"FSS")
                    if app.CheckBox_Find_Highest_Peak.Value ~= 1
                        % final check to make sure exactly on dot 
                        app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,final_locking_on_margin); % trying to land on the exact dot 
                        % Starting search for brightest spot
                        Brightest_Spot_Search(app)   
                    end
    
                    % FSS process
                    %----------------------------------------------------------------------------------------------------------------------------------------------
                    AppLogger_FSS(app,"Starting FSS rotating process",'black'); 
                    app.MyFuncs.FSS_Process(app.ell_motor,QD_counter,app.vid_ASI,app.src_ASI,app.Spectrometer_gratting,convertCharsToStrings(app.SaveRawImgDropDown.Value),str2num(app.FSSTotalAngleDropDown.Value))
                    AppLogger_FSS(app," FSS rotating process complete",app.Darkgreen);
                end


            end
        end

        % Value changed function: CheckBox_Photon_Scan
        function CheckBox_Photon_ScanValueChanged(app, event)
            value = app.CheckBox_Photon_Scan.Value;
            if value == 1
                app.Save_Photon_Scan = "Yes";
            else 
                app.Save_Photon_Scan = "No";
            end
        end

        % Button pushed function: CaptureCurrentPlotButton
        function CaptureCurrentPlotButtonPushed(app, event)
            ROI_Settings = load("ROI_Settings.mat"); 

            app.Sound_Button_pressed()
            app.CaptureCurrentPlotButton.BackgroundColor = 'g'; 
            pause(0.2)

            % disable button
            app.CaptureCurrentPlotButton.Enable = "off";

             % Snapping a photo and gray scaling it 
            trigger(app.vid_ASI)
            while(app.vid_ASI.FramesAcquired < 3)
                pause(eps)
            end
            [Emission_Reading_Img] = getdata(app.vid_ASI,app.vid_ASI.FramesPerTrigger);

            Emission_Reading_Img = mean(Emission_Reading_Img, 4);
            if size(Emission_Reading_Img,3) == 3 % checks if image is rgb
                Emission_Reading_Img = rgb2gray(Emission_Reading_Img);
            end

            % grab background average
            background_Img = app.background_img; 

            
            % Auto-size vertical window
            [height,~] = size(Emission_Reading_Img); 
            central_row = ROI_Settings.ROI_Middle_Pixel; 
            window_size = round(height*ROI_Settings.ROI_Percentage_Decimal); % ROI_Percent below and above the central_row 
            valid_rows = max(1, central_row - window_size):min(height, central_row + window_size);

            
            % Sum spectrum within window
            spectrum_sum = sum(Emission_Reading_Img(valid_rows, :), 1);
            background_Img = sum(background_Img(valid_rows, :), 1);
            
            spectrum_sum = spectrum_sum - background_Img ;
            
            % Create a new figure
            figure;
            
            % Make the figure invisible
            set(gcf, 'Visible', 'on');
            
            % add required amount
            updated_wavelength = app.wvlength; 
            
            
            % Plot the spectrum data
            plot(updated_wavelength, spectrum_sum);
            % Set exact limits for the x-axis
            xlim([min(updated_wavelength), max(updated_wavelength)]);
            
            % Set title and labels
            title_font = sprintf("Spectrum Plot");
            title(title_font);
            xlabel('Wavelength [nm]');
            ylabel('Arb. Counts');
    
            % plot image
            plot_img = gcf; 
            
            % Adjust the figure size
            set(gcf, 'Position', [100, 100, 1200, 800]); % [left bottom width height]
    
            % finding the main peaks 
            [pks,locs,~,~] = findpeaks(spectrum_sum,app.wvlength,'SortStr','descend','NPeaks',3,'MinPeakDistance',0.5);
    
            % Create text strings for top 3 peaks
            peak_text = cell(3,1);
            for n = 1:min(3,length(pks))
                peak_text{n} = sprintf('Peak %d: %.3f nm Abs. Counts: %.2f', n, locs(n),pks(n));
            end
            
            % Add text box in top-right corner
            text(0.95, 0.95, peak_text,...
                'Units', 'normalized',...
                'HorizontalAlignment', 'right',...
                'VerticalAlignment', 'top',...
                'BackgroundColor', [1 1 1 0.7],... % Semi-transparent white
                'EdgeColor', 'k',...
                'FontSize', 10,...
                'Margin', 3);
            
            % Set title and labels
            title_font = sprintf("QD Spectrum Plot");
            title(title_font);
            xlabel('Wavelength [nm]');
            ylabel('Arb. Counts');

            % renable button 
            app.CaptureCurrentPlotButton.BackgroundColor = app.DefaultColour; 
            app.CaptureCurrentPlotButton.Enable = "on"; 

        end

        % Button pushed function: ConfirmSettingButton
        function ConfirmSettingButtonPushed(app, event)
            app.Sound_Button_pressed()
            app.ConfirmSettingButton.BackgroundColor = 'g'; 
            pause(0.2)
            app.ConfirmSettingButton.BackgroundColor = app.DefaultColour; 
            
            
            % Confirm the newly defined x and y step size by the user 
            app.data.X_Fast_Movement_Step = app.XStepNumberEditField.Value; % update x to new step size
            app.data.Y_Fast_Movement_Step = app.YStepNumberEditField.Value; % update y to new step size

            % Saving new settings to the settings.mat file 
            X_Fast_Movement_Step = app.data.X_Fast_Movement_Step;
            Y_Fast_Movement_Step = app.data.Y_Fast_Movement_Step;
            save("Spectrometer_Settings.mat","X_Fast_Movement_Step","Y_Fast_Movement_Step","-append")

            % Informing the user the desired settings were set
            Settings_confirmed_text = sprintf("X Step Size has been set to %d\nY Step Size has been set to %d",X_Fast_Movement_Step,Y_Fast_Movement_Step);
            AppLogger_Specific(app,Settings_confirmed_text,app.Darkgreen);
            
        end

        % Button pushed function: TestXStepSizeButton
        function TestXStepSizeButtonPushed(app, event)

            app.Sound_Button_pressed()
            app.TestXStepSizeButton.BackgroundColor = 'g'; 
            app.TestXStepSizeButton.Enable = 'off'; 


            % Setting the frequency and voltage of the fast movement 
            fprintf(app.ANC300,"setv 1 30"); 
            fprintf(app.ANC300,"setf 1 30"); 
            pause(2)
            
            % Movement command X
            X_Movement = sprintf("stepu 1 %d",app.data.X_Fast_Movement_Step);
            fprintf(app.ANC300,X_Movement); 
            app.MyFuncs.step_queue_improved(app.ANC300,1,20)

            % Setting the frequency and voltage back to default
            fprintf(app.ANC300,"setv 1 12"); 
            fprintf(app.ANC300,"setf 1 20"); 
            pause(2)

            % Re-enabling button 
            app.TestXStepSizeButton.BackgroundColor = app.DefaultColour; 
            app.TestXStepSizeButton.Enable = 'on'; 
            
        end

        % Button pushed function: TestYStepSizeButton
        function TestYStepSizeButtonPushed(app, event)
            
            app.Sound_Button_pressed()
            app.TestYStepSizeButton.BackgroundColor = 'g'; 
            app.TestYStepSizeButton.Enable = 'off'; 

            % Setting the frequency and voltage of the fast movement 
            fprintf(app.ANC300,"setv 2 30"); 
            fprintf(app.ANC300,"setf 2 30"); 
            pause(2)
            
            % Movement command X
            Y_Movement = sprintf("stepu 2 %d",app.data.Y_Fast_Movement_Step);
            fprintf(app.ANC300,Y_Movement); 
            app.MyFuncs.step_queue_improved(app.ANC300,2,15)

            % Setting the frequency and voltage back to default
            fprintf(app.ANC300,"setv 2 12"); 
            fprintf(app.ANC300,"setf 2 20"); 
            pause(2)

            % Re-enabling button 
            app.TestYStepSizeButton.BackgroundColor = app.DefaultColour; 
            app.TestYStepSizeButton.Enable = 'on'; 
        end

        % Value changed function: Save_Raw_Img_Raster
        function Save_Raw_Img_RasterValueChanged(app, event)
            value = app.Save_Raw_Img_Raster.Value;
            % Check box that allows user to either save or not save the raw imgs
            if value 
                app.Raw_Img_Raster_Option = "Yes";
            else
                app.Raw_Img_Raster_Option = "No";
            end
        end

        % Button down function: ANC300Tab
        function ANC300TabButtonDown(app, event)
            % Updating things visually
            app.X_Freq.Value = 20; app.Y_Freq.Value = 20;
            app.X_Volt.Value = 12; app.Y_Volt.Value = 12; 
            app.X_Mode.Value = "STP"; app.Y_Mode.Value = "STP"; 


        end

        % Value changed function: StartStopASICameraButton
        function StartStopASICameraButtonValueChanged(app, event)
        value = app.StartStopASICameraButton.Value;
         
        if value
            app.Sound_Button_pressed()
            app.StartStopASICameraButton.BackgroundColor = 'g';
            screenSize = get(0, 'ScreenSize'); % gets pixel resolution of screen
            dimensions_ASI = app.vid_ASI.VideoResolution; % Get the resolution of the video input
            width = dimensions_ASI(1); % Video width
            height = dimensions_ASI(2); % Video height

            % Shrink the figure to a fraction of the original resolution
            figWidth = round(width / 8);
            figHeight = round(height / 8);

            % Position the window on the screen (shifted right and centered vertically)
            left = (screenSize(3) - figWidth) * 0.75; 
            bottom = (screenSize(4) - figHeight) / 2;

            % Create a figure with specified dimensions and position
            app.UI_Fig_ASI = figure('Units', 'pixels', 'Position', [left, bottom, figWidth, figHeight], "NumberTitle", "off",'WindowStyle', 'modal');

            % Create axes within the figure (slightly smaller for padding)
            app.CameraAxesASI = axes('Units', 'pixels', 'Position', [10, 10, figWidth - 20, figHeight - 20]);

            % Get the video resolution and number of bands (for RGB or grayscale)
            vidRes = get(app.vid_ASI, 'VideoResolution');
            nBands = get(app.vid_ASI, 'NumberOfBands');

            % Initialize the image handle in the specified axes, with the same resolution as the video
            hImage = image(zeros(vidRes(2), vidRes(1), nBands));
            %disp(class(app.UI_Fig_ASI))

            % Start the preview on the video input object, passing the axes handle for display
            preview(app.vid_ASI, hImage);
            
            
            % Define the middle line and percentage offset
            if app.ROI.ROI_Middle == 0
                middleLineY = vidRes(2) / 2; % Middle of the video height
            elseif app.ROI.ROI_Middle > 0
                half_res = vidRes(2)/2; % center of ASI Vid
                half_res_interval = ones(1,50)*round(half_res/50); % defining a matrix for all the settings 
                interval = 1:1:50;  
                Indexed_Interval = half_res_interval .* interval;  % final index being used for catching data
                Indexed_Interval = flip(Indexed_Interval,2);
                middleLineY = Indexed_Interval(abs(app.ROI_Middle.Value)); 
            elseif app.ROI.ROI_Middle < 0
                half_res = vidRes(2)/2; % center of ASI Vid
                half_res_interval = ones(1,50)*round(half_res/50); % defining a matrix for all the settings 
                interval = 1:1:50;  
                Indexed_Interval = half_res_interval .* interval;  % final index being used for catching data
                middleLineY = half_res + Indexed_Interval(abs(app.ROI_Middle.Value)); 
            end
            offsetY = vidRes(2) * (app.ROI_Percentage.Value/100); % Calculate the offset in pixels
            % Draw the upper line
            app.upperLine = line(app.CameraAxesASI, [1, vidRes(1)], [max(1, middleLineY - offsetY), max(1, middleLineY - offsetY)], ...
                'Color', 'r', 'LineWidth', 2, 'LineStyle', '--');
            % Draw the lower line
            app.lowerLine = line(app.CameraAxesASI, [1, vidRes(1)], [min(vidRes(2),middleLineY + offsetY), min(vidRes(2),middleLineY + offsetY)], ...
                'Color', 'r', 'LineWidth', 2, 'LineStyle', '--');
            % Bring the lines to the top of the axes
            uistack([app.upperLine, app.lowerLine], 'top');
        else
            app.Sound_Button_pressed()
            app.StartStopASICameraButton.BackgroundColor = app.DefaultColour;
            closepreview(app.vid_ASI);
            if isvalid(app.UI_Fig_ASI)
                close(app.UI_Fig_ASI);
            end
        end
        end

        % Value changed function: ROI_Middle
        function ROI_MiddleValueChanged(app, event)
            
            % Check the figure is open and exists 
            if isprop(app, 'UI_Fig_ASI') && isvalid(app.UI_Fig_ASI)
            else
                app.TextBoxCameraSettings.Value = "Figure is closed, please open ASI Feed";
                app.TextBoxCameraSettings.FontColor = app.DarkRed;
                return
            end 
            
            % delete  lines from before 
            delete(app.upperLine);
            delete(app.lowerLine);
           

            % Get the video resolution and dimensions 
            vidRes = get(app.vid_ASI, 'VideoResolution');
           
            % Define the middle line and percentage offset
            if app.ROI_Middle.Value == 0
                middleLineY = vidRes(2) / 2; % Middle of the video height
            elseif app.ROI_Middle.Value > 0
                half_res = vidRes(2)/2; % center of ASI Vid
                half_res_interval = ones(1,50)*round(half_res/50); % defining a matrix for all the settings 
                interval = 1:1:50;  
                Indexed_Interval = half_res_interval .* interval;  % final index being used for catching data
                Indexed_Interval = flip(Indexed_Interval,2);
                middleLineY = Indexed_Interval(abs(app.ROI_Middle.Value)); 
            elseif app.ROI_Middle.Value < 0
                half_res = vidRes(2)/2; % center of ASI Vid
                half_res_interval = ones(1,50)*round(half_res/50); % defining a matrix for all the settings 
                interval = 1:1:50;  
                Indexed_Interval = half_res_interval .* interval;  % final index being used for catching data
                middleLineY = half_res + Indexed_Interval(abs(app.ROI_Middle.Value)); 
            end

            % saving real setting to the ROI settings mat file 
            app.ROI_Middle_Pixel = middleLineY; 
            ROI_Middle_Pixel =  app.ROI_Middle_Pixel; 
            save("ROI_Settings.mat","ROI_Middle_Pixel","-append")

            % saving UI setting to the ROI settings mat file 
            ROI_Middle = app.ROI_Middle.Value; 
            save("ROI_Settings.mat","ROI_Middle","-append")
            
            offsetY = vidRes(2) * (app.ROI_Percentage.Value/100); % Calculate the offset in pixels
             % Draw the upper line
            app.upperLine = line(app.CameraAxesASI, [1, vidRes(1)], [max(1, middleLineY - offsetY), max(1, middleLineY - offsetY)], ...
                'Color', 'r', 'LineWidth', 2, 'LineStyle', '--');
            % Draw the lower line
            app.lowerLine = line(app.CameraAxesASI, [1, vidRes(1)], [min(vidRes(2),middleLineY + offsetY), min(vidRes(2),middleLineY + offsetY)], ...
                'Color', 'r', 'LineWidth', 2, 'LineStyle', '--');
            % Bring the lines to the top of the axes
            uistack([app.upperLine, app.lowerLine], 'top');
        end

        % Value changed function: ROI_Percentage
        function ROI_PercentageValueChanged(app, event)
           % Check the figure is open and exists 
            if isprop(app, 'UI_Fig_ASI') && isvalid(app.UI_Fig_ASI)
            else
                app.TextBoxCameraSettings.Value = "Figure is closed, please open ASI Feed";
                app.TextBoxCameraSettings.FontColor = app.DarkRed;
                return
            end 
            
            % delete  lines from before 
            delete(app.upperLine);
            delete(app.lowerLine);
           

            % Get the video resolution and dimensions 
            vidRes = get(app.vid_ASI, 'VideoResolution');

            % saving real setting to the ROI settings mat file 
            ROI_Percentage_Decimal = (app.ROI_Percentage.Value/100); 
            save("ROI_Settings.mat","ROI_Percentage_Decimal","-append")
            app.ROI_Percentage_Decimal = ROI_Percentage_Decimal; 

            % saving GUI setting to the ROI settings mat file 
            ROI_Screen_Percent = app.ROI_Percentage.Value; 
            save("ROI_Settings.mat","ROI_Screen_Percent","-append")

            % Define the middle line and percentage offset
            if app.ROI_Middle.Value == 0
                middleLineY = vidRes(2) / 2; % Middle of the video height
            elseif app.ROI_Middle.Value > 0
                half_res = vidRes(2)/2; % center of ASI Vid
                half_res_interval = ones(1,50)*round(half_res/50); % defining a matrix for all the settings 
                interval = 1:1:50;  
                Indexed_Interval = half_res_interval .* interval;  % final index being used for catching data
                Indexed_Interval = flip(Indexed_Interval,2);
                middleLineY = Indexed_Interval(abs(app.ROI_Middle.Value)); 
            elseif app.ROI_Middle.Value < 0
                half_res = vidRes(2)/2; % center of ASI Vid
                half_res_interval = ones(1,50)*round(half_res/50); % defining a matrix for all the settings 
                interval = 1:1:50;  
                Indexed_Interval = half_res_interval .* interval;  % final index being used for catching data
                middleLineY = half_res + Indexed_Interval(abs(app.ROI_Middle.Value)); 
            end
            offsetY = vidRes(2) * (app.ROI_Percentage.Value/100); % Calculate the offset in pixels
            % Draw the upper line
            app.upperLine = line(app.CameraAxesASI, [1, vidRes(1)], [max(1, middleLineY - offsetY), max(1, middleLineY - offsetY)], ...
                'Color', 'r', 'LineWidth', 2, 'LineStyle', '--');
            % Draw the lower line
            app.lowerLine = line(app.CameraAxesASI, [1, vidRes(1)], [min(vidRes(2),middleLineY + offsetY), min(vidRes(2),middleLineY + offsetY)], ...
                'Color', 'r', 'LineWidth', 2, 'LineStyle', '--');
            % Bring the lines to the top of the axes
            uistack([app.upperLine, app.lowerLine], 'top');
        end

        % Button pushed function: UploadListButton
        function UploadListButtonPushed(app, event)
            % when presses takes all qd from text file and uploads to QD list
            file_name = "C:\Users\Quantum Dot\Desktop\Bera Yavuz - ANC300 Movement and Images\QD_Data\QD_Search.txt";
            app.QD_matrix = readmatrix(file_name); 
            success_msg = sprintf("QD list has been uploaded");
            AppLogger_FSS(app,success_msg,app.Darkgreen)
        end

        % Button pushed function: EmptyListButton
        function EmptyListButtonPushed(app, event)
            app.QD_matrix = []; 
        end

        % Button pushed function: OKButton_X
        function OKButton_XPushed(app, event)
            app.Sound_Button_pressed()
            if ~isempty(app.X_Freq.Value) & ~isempty(app.X_Volt.Value) & ~ isempty(app.X_Mode.Value)
                app.OKButton_X.BackgroundColor = 'g';
                pause(0.2)
                app.OKButton_X.BackgroundColor = app.DefaultColour; 
                AppLogger(app,app.Freq_Axis_X,'black'); fprintf(app.ANC300,app.Serial_Freq_Axis_X);
                AppLogger(app,app.Volt_Axis_X,'black'); fprintf(app.ANC300,app.Serial_Volt_Axis_X);
                AppLogger(app,app.Mode_Axis_X,'black'); fprintf(app.ANC300,app.Serial_Mode_Axis_X); 
            else
                AppLogger(app,'One or more required commands are not set.')            
            end
        end

        % Button pushed function: OKButton_Y
        function OKButton_YPushed(app, event)
            app.Sound_Button_pressed()
            if ~isempty(app.Y_Freq.Value) & ~isempty(app.Y_Volt.Value) & ~ isempty(app.Y_Mode.Value)
                app.OKButton_Y.BackgroundColor = 'g';
                pause(0.2)
                app.OKButton_Y.BackgroundColor = [1 1 1]; 
                AppLogger(app,app.Freq_Axis_Y,'black'); fprintf(app.ANC300,app.Serial_Freq_Axis_Y);
                AppLogger(app,app.Volt_Axis_Y,'black'); fprintf(app.ANC300,app.Serial_Volt_Axis_Y);
                AppLogger(app,app.Mode_Axis_Y,'black'); fprintf(app.ANC300,app.Serial_Mode_Axis_Y);
            else
                AppLogger(app,'One or more required commands are not set.');
            end
        end

        % Value changed function: X_Freq
        function X_FreqValueChanged(app, event)
            value = app.X_Freq.Value;
            ValueStr = num2str(value);
            app.Serial_Freq_Axis_X = sprintf("setf 1 %d",value); 
            app.Freq_Axis_X = sprintf("The Frequency in the X-axis has been set to %s Hz",ValueStr);
            
        end

        % Value changed function: X_Volt
        function X_VoltValueChanged(app, event)
            value = app.X_Volt.Value;
            ValueStr = num2str(value);
            app.Serial_Volt_Axis_X = sprintf("setv 1 %f0.3",value); 
            app.Volt_Axis_X = sprintf("The Voltage in the X-axis has been set to %s Volts",ValueStr);
            
        end

        % Value changed function: Y_Freq
        function Y_FreqValueChanged(app, event)
            value = app.Y_Freq.Value;
            ValueStr = num2str(value);
            app.Serial_Freq_Axis_Y = sprintf("setf 2 %d",value); 
            app.Freq_Axis_Y = sprintf("The Frequency in the Y-axis has been set to %s Hz",ValueStr);
            
        end

        % Value changed function: Y_Volt
        function Y_VoltValueChanged(app, event)
            value = app.Y_Volt.Value;
            ValueStr = num2str(value);
            app.Serial_Volt_Axis_Y = sprintf("setv 2 %f0.3",value); 
            app.Volt_Axis_Y = sprintf("The Voltage in the Y-axis has been set to %s Volts",ValueStr);
            
        end

        % Value changed function: X_Mode
        function X_ModeValueChanged(app, event)
            value = app.X_Mode.Value;
            if value == 'GND'
                value = 'ground'; 
                serial_Value = 'gnd'; 
            else 
                value = 'stepping'; 
                serial_Value = 'stp'; 
            end
            app.Serial_Mode_Axis_X = sprintf("setm 1 %s",serial_Value); 
            app.Mode_Axis_X = sprintf("The X-axis has been set to %s mode",lower(value)); 
            
        end

        % Value changed function: Y_Mode
        function Y_ModeValueChanged(app, event)
            value = app.Y_Mode.Value;
            if value == 'GND'
                value = 'ground'; 
                serial_Value = 'gnd';
            else 
                value = 'stepping'; 
                serial_Value = 'stp';
            end
            app.Serial_Mode_Axis_Y = sprintf("setm 2 %s",serial_Value);
            app.Mode_Axis_Y = sprintf("The Y-axis has been set to %s mode",lower(value));
            
        end

        % Image clicked function: Stop_Everything
        function Stop_EverythingImageClicked(app, event)
            app.Sound_Button_pressed()
            fprintf(app.ANC300,"stop 1");
            fprintf(app.ANC300,"stop 2");
            fprintf(app.ANC300,"stop 3");
            AppLogger(app,'ALL STEPPING HAS BEEN STOPPED!','red');
        end

        % Button pushed function: PrecisionLockButton
        function PrecisionLockButtonPushed(app, event)
            readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB","");
            QD_counter = readQD_position.lastLine;
            final_locking_on_margin = 25;
            app.MyFuncs.Precision_Locking_Matlab(app.ANC300,QD_counter,app.vid_UI,app.src_UI,final_locking_on_margin);
        end

        % Button pushed function: UpdateQDButton_List
        function UpdateQDButton_ListPushed(app, event)
            app.Sound_Button_pressed()
            app.UpdateQDButton_List.BackgroundColor = 'g'; 
            pause(0.2)
            app.UpdateQDButton_List.BackgroundColor = app.DefaultColour; 

            % Updating Current location of QD 
            readQD_position = app.MyFuncs.QD_tracking_N_Identification("","","Read","LAB",""); 
            app.QD_location_List.Text = mat2str(readQD_position.lastLine); 

            AppLogger_FSS(app,"Current QD updated",app.Darkgreen) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RasterScanTab = uitab(app.TabGroup);
            app.RasterScanTab.Title = 'Raster Scan';
            app.RegionSelectionPanel = uipanel(app.RasterScanTab);
            app.RegionSelectionPanel.Title = 'Region Selection';
            app.NumberofRowsEditField_2Label = uilabel(app.RegionSelectionPanel);
            app.NumberofRowsEditField_2Label.Text = 'Number of Rows';
            app.NumberOfRows = uieditfield(app.RegionSelectionPanel, 'numeric');
            app.NumberOfRows.Limits = [1 200];
            app.NumberOfRows.RoundFractionalValues = 'on';
            app.NumberOfRows.Tooltip = {'Axis in direction towards the lower left and upper right of  QD chip'};
            app.NumberOfRows.Value = 200;
            app.NumberofColumnsLabel = uilabel(app.RegionSelectionPanel);
            app.NumberofColumnsLabel.Text = 'Number of Columns';
            app.NumberOfColumns = uieditfield(app.RegionSelectionPanel, 'numeric');
            app.NumberOfColumns.Limits = [1 200];
            app.NumberOfColumns.RoundFractionalValues = 'on';
            app.NumberOfColumns.Tooltip = {'Axis in direction  towards the lower right and upper left of QD chip'};
            app.NumberOfColumns.Value = 1;
            app.OKButton = uibutton(app.RegionSelectionPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.RasterScanningOvertheNanoWireChipLabel = uilabel(app.RasterScanTab);
            app.RasterScanningOvertheNanoWireChipLabel.Text = 'Raster Scanning Over the NanoWire Chip';
            app.TextArea_RasterScan = uitextarea(app.RasterScanTab);
            app.TextArea_RasterScan.Placeholder = 'Update Text Will Appear Here';
            app.CurrentLocationofQDPanel = uipanel(app.RasterScanTab);
            app.CurrentLocationofQDPanel.Title = 'Current Location of QD ';
            app.QD_location = uilabel(app.CurrentLocationofQDPanel);
            app.QD_location.Text = 'Current QD';
            app.UpdateQDButton = uibutton(app.CurrentLocationofQDPanel, 'push');
            app.UpdateQDButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateQDButtonPushed, true);
            app.UpdateQDButton.Tooltip = {'Press this button to make sure QD is updated when switching between tabs for visual purposes'};
            app.UpdateQDButton.Text = 'Update QD';
            app.StartStopRasterScanButton = uibutton(app.RasterScanTab, 'state');
            app.StartStopRasterScanButton.ValueChangedFcn = createCallbackFcn(app, @StartStopRasterScanButtonValueChanged, true);
            app.StartStopRasterScanButton.Text = 'Start/Stop Raster Scan';
            app.Panel = uipanel(app.RasterScanTab);
            app.Current_Plot = uiimage(app.Panel);
            app.CurrentQDPlotLabel = uilabel(app.RasterScanTab);
            app.CurrentQDPlotLabel.Text = 'Current QD Plot ';
            app.CurrentRowCompletion = uicheckbox(app.RasterScanTab);
            app.CurrentRowCompletion.ValueChangedFcn = createCallbackFcn(app, @CurrentRowCompletionValueChanged, true);
            app.CurrentRowCompletion.Tooltip = {'If user clicks on this button the raster scan will finish the current row it is on before doing the inputted region'};
            app.CurrentRowCompletion.Text = 'Complete Current Row';
            app.Save_Raw_Img_Raster = uicheckbox(app.RasterScanTab);
            app.Save_Raw_Img_Raster.ValueChangedFcn = createCallbackFcn(app, @Save_Raw_Img_RasterValueChanged, true);
            app.Save_Raw_Img_Raster.Tooltip = {'If user clicks on this button the raw spectrum photos from the asi will be saved '};
            app.Save_Raw_Img_Raster.Text = 'Save Raw Images';
            app.Save_Position_Images = uicheckbox(app.RasterScanTab);
            app.Save_Position_Images.Tooltip = {'If user clicks on this button the raster scan will save the position photo from Ueye for each dot'};
            app.Save_Position_Images.Text = 'Save Position Images';
            app.SpecificMovementTab = uitab(app.TabGroup);
            app.SpecificMovementTab.Title = 'Specific Movement';
            app.MovingtoSpecificQDonChipLabel = uilabel(app.SpecificMovementTab);
            app.MovingtoSpecificQDonChipLabel.Text = 'Moving to Specific QD on Chip';
            app.CurrentLocationofQDPanel_SpecificQD = uipanel(app.SpecificMovementTab);
            app.CurrentLocationofQDPanel_SpecificQD.Title = 'Current Location of QD ';
            app.QD_location_SpecificQD = uilabel(app.CurrentLocationofQDPanel_SpecificQD);
            app.QD_location_SpecificQD.Text = 'Current QD';
            app.UpdateQDButton_Specific = uibutton(app.CurrentLocationofQDPanel_SpecificQD, 'push');
            app.UpdateQDButton_Specific.ButtonPushedFcn = createCallbackFcn(app, @UpdateQDButton_SpecificPushed, true);
            app.UpdateQDButton_Specific.Tooltip = {'Press this button to make sure QD is updated when switching between tabs for visual purposes'};
            app.UpdateQDButton_Specific.Text = 'Update QD';
            app.CurrentLocationofQDPanel_SpecificQD_2 = uipanel(app.SpecificMovementTab);
            app.CurrentLocationofQDPanel_SpecificQD_2.Title = 'Inquired QD';
            app.Bracket2 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_2);
            app.Bracket2.Text = '[';
            app.Bracket1 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_2);
            app.Bracket1.Text = ']';
            app.Inquired_Row = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_2, 'numeric');
            app.Inquired_Row.Limits = [1 200];
            app.Inquired_Row.RoundFractionalValues = 'on';
            app.Inquired_Row.AllowEmpty = 'on';
            app.Inquired_Row.Tooltip = {'Row ID of Inquired QD'};
            app.Inquired_Row.Placeholder = ' ';
            app.Inquired_Row.Value = [];
            app.Inquired_Column = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_2, 'numeric');
            app.Inquired_Column.Limits = [1 200];
            app.Inquired_Column.RoundFractionalValues = 'on';
            app.Inquired_Column.AllowEmpty = 'on';
            app.Inquired_Column.Tooltip = {'Column ID of Inquired QD'};
            app.Inquired_Column.Value = [];
            app.StartButton = uibutton(app.CurrentLocationofQDPanel_SpecificQD_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ROWLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_2);
            app.ROWLabel.Text = 'ROW';
            app.COLUMNLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_2);
            app.COLUMNLabel.Text = 'COLUMN';
            app.ConfirmButton = uibutton(app.CurrentLocationofQDPanel_SpecificQD_2, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.PlotDropDown = uidropdown(app.CurrentLocationofQDPanel_SpecificQD_2);
            app.PlotDropDown.Items = {'No Plot', 'Make Plot'};
            app.PlotDropDown.Value = 'No Plot';
            app.TextArea_RasterScan_Specific = uitextarea(app.SpecificMovementTab);
            app.TextArea_RasterScan_Specific.Placeholder = 'Update Text Will Appear Here';
            app.SpecificMovementSettingsahdChangingBigMovementPanel = uipanel(app.SpecificMovementTab);
            app.SpecificMovementSettingsahdChangingBigMovementPanel.Title = 'Specific Movement Settings ahd Changing (Big Movement)';
            app.XStepNumberEditFieldLabel = uilabel(app.SpecificMovementSettingsahdChangingBigMovementPanel);
            app.XStepNumberEditFieldLabel.Text = 'X Step Number';
            app.XStepNumberEditField = uieditfield(app.SpecificMovementSettingsahdChangingBigMovementPanel, 'numeric');
            app.YStepNumberEditFieldLabel = uilabel(app.SpecificMovementSettingsahdChangingBigMovementPanel);
            app.YStepNumberEditFieldLabel.Text = 'Y Step Number';
            app.YStepNumberEditField = uieditfield(app.SpecificMovementSettingsahdChangingBigMovementPanel, 'numeric');
            app.ConfirmSettingButton = uibutton(app.SpecificMovementSettingsahdChangingBigMovementPanel, 'push');
            app.ConfirmSettingButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSettingButtonPushed, true);
            app.ConfirmSettingButton.Text = {'Confirm'; 'Setting'};
            app.TestXStepSizeButton = uibutton(app.SpecificMovementSettingsahdChangingBigMovementPanel, 'push');
            app.TestXStepSizeButton.ButtonPushedFcn = createCallbackFcn(app, @TestXStepSizeButtonPushed, true);
            app.TestXStepSizeButton.Text = 'Test X Step Size';
            app.TestYStepSizeButton = uibutton(app.SpecificMovementSettingsahdChangingBigMovementPanel, 'push');
            app.TestYStepSizeButton.ButtonPushedFcn = createCallbackFcn(app, @TestYStepSizeButtonPushed, true);
            app.TestYStepSizeButton.Text = 'Test Y Step Size';
            app.PrecisionLockButton = uibutton(app.SpecificMovementSettingsahdChangingBigMovementPanel, 'push');
            app.PrecisionLockButton.ButtonPushedFcn = createCallbackFcn(app, @PrecisionLockButtonPushed, true);
            app.PrecisionLockButton.Tooltip = {'Locks on to closest QD'};
            app.PrecisionLockButton.Text = 'Precision Lock';
            app.LEDFindTab = uitab(app.TabGroup);
            app.LEDFindTab.Title = 'LED Find';
            app.LEDCoordinateFinderLabel = uilabel(app.LEDFindTab);
            app.LEDCoordinateFinderLabel.Text = 'LED Coordinate Finder';
            app.FindLEDCoordinateButton = uibutton(app.LEDFindTab, 'push');
            app.FindLEDCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @FindLEDCoordinateButtonPushed, true);
            app.FindLEDCoordinateButton.Text = 'Find LED Coordinate';
            app.LEDCoordinateFindingProcessPanel = uipanel(app.LEDFindTab);
            app.LEDCoordinateFindingProcessPanel.Title = 'LED Coordinate Finding Process ';
            app.Original_Img = uiimage(app.LEDCoordinateFindingProcessPanel);
            app.Filtered = uiimage(app.LEDCoordinateFindingProcessPanel);
            app.Final = uiimage(app.LEDCoordinateFindingProcessPanel);
            app.RawImageLabel = uilabel(app.LEDCoordinateFindingProcessPanel);
            app.RawImageLabel.Text = 'Raw Image';
            app.FilteredImageLabel = uilabel(app.LEDCoordinateFindingProcessPanel);
            app.FilteredImageLabel.Text = 'Filtered Image';
            app.FinalImageLabel = uilabel(app.LEDCoordinateFindingProcessPanel);
            app.FinalImageLabel.Text = 'Final Image';
            app.TextArea_LED = uitextarea(app.LEDFindTab);
            app.TextArea_LED.Placeholder = 'Update Text Will Appear Here';
            app.TextArea_LED.Value = {'IMPORTANT NOTE: Make sure to open Ueye Camera  and slightly move until excitation laser is very visible and not being deformed due to a QD'; ''};
            app.CurrentLocationofQDPanel_SpecificQD_3 = uipanel(app.LEDFindTab);
            app.CurrentLocationofQDPanel_SpecificQD_3.Title = 'LED Coordinate';
            app.LED_Coordinate = uilabel(app.CurrentLocationofQDPanel_SpecificQD_3);
            app.LED_Coordinate.Text = 'LED Coords';
            app.ManualUpdatingTab = uitab(app.TabGroup);
            app.ManualUpdatingTab.Title = 'Manual Updating';
            app.ManuallyUpdatedSettingsLabel = uilabel(app.ManualUpdatingTab);
            app.ManuallyUpdatedSettingsLabel.Text = 'Manually Updated Settings';
            app.CurrentLocationofQDPanel_SpecificQD_4 = uipanel(app.ManualUpdatingTab);
            app.CurrentLocationofQDPanel_SpecificQD_4.Title = 'Manual QD';
            app.Bracket2_2 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_4);
            app.Bracket2_2.Text = '[';
            app.Bracket1_2 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_4);
            app.Bracket1_2.Text = ']';
            app.Inquired_Row_Manual = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_4, 'numeric');
            app.Inquired_Row_Manual.Limits = [1 200];
            app.Inquired_Row_Manual.RoundFractionalValues = 'on';
            app.Inquired_Row_Manual.AllowEmpty = 'on';
            app.Inquired_Row_Manual.Tooltip = {'Row ID of Inquired QD'};
            app.Inquired_Row_Manual.Placeholder = ' ';
            app.Inquired_Row_Manual.Value = [];
            app.Inquired_Column_Manual = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_4, 'numeric');
            app.Inquired_Column_Manual.Limits = [1 200];
            app.Inquired_Column_Manual.RoundFractionalValues = 'on';
            app.Inquired_Column_Manual.AllowEmpty = 'on';
            app.Inquired_Column_Manual.Tooltip = {'Column ID of Inquired QD'};
            app.Inquired_Column_Manual.Value = [];
            app.ROWLabel_2 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_4);
            app.ROWLabel_2.Text = 'ROW';
            app.COLUMNLabel_2 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_4);
            app.COLUMNLabel_2.Text = 'COLUMN';
            app.Confirm_QD_Manual = uibutton(app.CurrentLocationofQDPanel_SpecificQD_4, 'push');
            app.Confirm_QD_Manual.ButtonPushedFcn = createCallbackFcn(app, @Confirm_QD_ManualButtonPushed, true);
            app.Confirm_QD_Manual.Text = 'Confirm';
            app.LastDirectionOverrideLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_4);
            app.LastDirectionOverrideLabel.Text = 'Last Direction Override ';
            app.Dropdown_direction = uidropdown(app.CurrentLocationofQDPanel_SpecificQD_4);
            app.Dropdown_direction.Items = {'bottomleft', 'topright'};
            app.Dropdown_direction.Value = 'bottomleft';
            app.CurrentLocationofQDPanel_SpecificQD_5 = uipanel(app.ManualUpdatingTab);
            app.CurrentLocationofQDPanel_SpecificQD_5.Title = 'Manual XY FACTOR';
            app.Bracket2_3 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.Bracket2_3.Text = '[';
            app.Bracket1_3 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.Bracket1_3.Text = ']';
            app.X_Factor_Manual = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_5, 'numeric');
            app.X_Factor_Manual.Limits = [0 Inf];
            app.X_Factor_Manual.AllowEmpty = 'on';
            app.X_Factor_Manual.Tooltip = {'Row ID of Inquired QD'};
            app.X_Factor_Manual.Placeholder = ' ';
            app.X_Factor_Manual.Value = [];
            app.Y_Factor_Manual = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_5, 'numeric');
            app.Y_Factor_Manual.Limits = [0 Inf];
            app.Y_Factor_Manual.AllowEmpty = 'on';
            app.Y_Factor_Manual.Tooltip = {'Column ID of Inquired QD'};
            app.Y_Factor_Manual.Value = [];
            app.XPixelsLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.XPixelsLabel.Text = 'X-Pixels';
            app.YPixelsLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.YPixelsLabel.Text = 'Y-Pixels';
            app.Confirm_XY_Manual = uibutton(app.CurrentLocationofQDPanel_SpecificQD_5, 'push');
            app.Confirm_XY_Manual.ButtonPushedFcn = createCallbackFcn(app, @Confirm_XY_ManualButtonPushed, true);
            app.Confirm_XY_Manual.Text = 'Confirm';
            app.Num_Steps = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_5, 'numeric');
            app.Num_Steps.Limits = [0 Inf];
            app.Num_Steps.AllowEmpty = 'on';
            app.Num_Steps.Tooltip = {'Row ID of Inquired QD'};
            app.Num_Steps.Placeholder = ' ';
            app.Num_Steps.Value = [];
            app.NumberofStepsLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.NumberofStepsLabel.Text = 'Number of Steps';
            app.Bracket2_6 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.Bracket2_6.Text = '[';
            app.Bracket1_6 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.Bracket1_6.Text = ']';
            app.X_Factor_Manual_Back = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_5, 'numeric');
            app.X_Factor_Manual_Back.Limits = [0 Inf];
            app.X_Factor_Manual_Back.AllowEmpty = 'on';
            app.X_Factor_Manual_Back.Tooltip = {'Row ID of Inquired QD'};
            app.X_Factor_Manual_Back.Placeholder = ' ';
            app.X_Factor_Manual_Back.Value = [];
            app.Y_Factor_Manual_Back = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_5, 'numeric');
            app.Y_Factor_Manual_Back.Limits = [0 Inf];
            app.Y_Factor_Manual_Back.AllowEmpty = 'on';
            app.Y_Factor_Manual_Back.Tooltip = {'Column ID of Inquired QD'};
            app.Y_Factor_Manual_Back.Value = [];
            app.ReverseMoveLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_5);
            app.ReverseMoveLabel.Text = 'Reverse Move';
            app.CurrentLocationofQDPanel_SpecificQD_6 = uipanel(app.ManualUpdatingTab);
            app.CurrentLocationofQDPanel_SpecificQD_6.Title = 'Manual LED Coordinate ';
            app.Bracket2_4 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_6);
            app.Bracket2_4.Text = '[';
            app.Bracket1_4 = uilabel(app.CurrentLocationofQDPanel_SpecificQD_6);
            app.Bracket1_4.Text = ']';
            app.X_Coordinate_Manual = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_6, 'numeric');
            app.X_Coordinate_Manual.Limits = [0 Inf];
            app.X_Coordinate_Manual.RoundFractionalValues = 'on';
            app.X_Coordinate_Manual.AllowEmpty = 'on';
            app.X_Coordinate_Manual.Tooltip = {'Row ID of Inquired QD'};
            app.X_Coordinate_Manual.Placeholder = ' ';
            app.X_Coordinate_Manual.Value = [];
            app.Y_Coordinate_Manual = uieditfield(app.CurrentLocationofQDPanel_SpecificQD_6, 'numeric');
            app.Y_Coordinate_Manual.Limits = [0 Inf];
            app.Y_Coordinate_Manual.RoundFractionalValues = 'on';
            app.Y_Coordinate_Manual.AllowEmpty = 'on';
            app.Y_Coordinate_Manual.Tooltip = {'Column ID of Inquired QD'};
            app.Y_Coordinate_Manual.Value = [];
            app.XCoordinateLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_6);
            app.XCoordinateLabel.Text = 'X-Coordinate';
            app.YCoordinateLabel = uilabel(app.CurrentLocationofQDPanel_SpecificQD_6);
            app.YCoordinateLabel.Text = 'Y-Coordinate';
            app.Confirm_LED_Manual = uibutton(app.CurrentLocationofQDPanel_SpecificQD_6, 'push');
            app.Confirm_LED_Manual.ButtonPushedFcn = createCallbackFcn(app, @Confirm_LED_ManualButtonPushed, true);
            app.Confirm_LED_Manual.Text = 'Confirm';
            app.TextArea_Manual = uitextarea(app.ManualUpdatingTab);
            app.TextArea_Manual.Placeholder = 'Update Text Will Appear Here';
            app.BackgroundCaptureforSpectroscopyPanel = uipanel(app.ManualUpdatingTab);
            app.BackgroundCaptureforSpectroscopyPanel.Title = 'Background Capture for Spectroscopy';
            app.CaptureBackgroundPhotosButton = uibutton(app.BackgroundCaptureforSpectroscopyPanel, 'push');
            app.CaptureBackgroundPhotosButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureBackgroundPhotosButtonPushed, true);
            app.CaptureBackgroundPhotosButton.Text = 'Capture Background Photos';
            app.DebugCurrentPhotoPanel = uipanel(app.ManualUpdatingTab);
            app.DebugCurrentPhotoPanel.Title = 'Debug Current Photo';
            app.QD_Photo_Debug = uibutton(app.DebugCurrentPhotoPanel, 'push');
            app.QD_Photo_Debug.ButtonPushedFcn = createCallbackFcn(app, @QD_Photo_DebugPushed, true);
            app.QD_Photo_Debug.Text = 'Capture QD Position Photo';
            app.NameofRawPhotoEditField = uieditfield(app.DebugCurrentPhotoPanel, 'text');
            app.NameofRawPhotoEditField.Placeholder = 'Photo Name';
            app.Confirm_Photo_Name = uibutton(app.DebugCurrentPhotoPanel, 'push');
            app.Confirm_Photo_Name.ButtonPushedFcn = createCallbackFcn(app, @Confirm_Photo_NameButtonPushed, true);
            app.Confirm_Photo_Name.Text = 'Confirm';
            app.CaptureCurrentPlotButton = uibutton(app.ManualUpdatingTab, 'push');
            app.CaptureCurrentPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureCurrentPlotButtonPushed, true);
            app.CaptureCurrentPlotButton.Text = {'Capture Current'; ' Plot'};
            app.CameraSettingsTab = uitab(app.TabGroup);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.Panel_2 = uipanel(app.CameraSettingsTab);
            app.ASISpectrometerCameraSettingsPanel = uipanel(app.Panel_2);
            app.ASISpectrometerCameraSettingsPanel.Title = 'ASI (Spectrometer Camera) Settings ';
            app.ExposureSecondsSliderLabel = uilabel(app.ASISpectrometerCameraSettingsPanel);
            app.ExposureSecondsSliderLabel.Text = {'Exposure'; '(Seconds)'};
            app.ExposureSecondsSlider = uislider(app.ASISpectrometerCameraSettingsPanel);
            app.ExposureSecondsSlider.ValueChangedFcn = createCallbackFcn(app, @ExposureSecondsSliderValueChanged, true);
            app.GainSliderLabel = uilabel(app.ASISpectrometerCameraSettingsPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.ASISpectrometerCameraSettingsPanel);
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GammaSliderLabel = uilabel(app.ASISpectrometerCameraSettingsPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.ASISpectrometerCameraSettingsPanel);
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.BrightnessSliderLabel = uilabel(app.ASISpectrometerCameraSettingsPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ASISpectrometerCameraSettingsPanel);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.EditField_Exposure = uieditfield(app.ASISpectrometerCameraSettingsPanel, 'numeric');
            app.EditField_Exposure.ValueChangedFcn = createCallbackFcn(app, @EditField_ExposureValueChanged, true);
            app.EditField_Gain = uieditfield(app.ASISpectrometerCameraSettingsPanel, 'numeric');
            app.EditField_Gain.RoundFractionalValues = 'on';
            app.EditField_Gain.ValueChangedFcn = createCallbackFcn(app, @EditField_GainValueChanged, true);
            app.EditField_Gamma = uieditfield(app.ASISpectrometerCameraSettingsPanel, 'numeric');
            app.EditField_Gamma.RoundFractionalValues = 'on';
            app.EditField_Gamma.ValueChangedFcn = createCallbackFcn(app, @EditField_GammaValueChanged, true);
            app.EditField_Brightness = uieditfield(app.ASISpectrometerCameraSettingsPanel, 'numeric');
            app.EditField_Brightness.RoundFractionalValues = 'on';
            app.EditField_Brightness.ValueChangedFcn = createCallbackFcn(app, @EditField_BrightnessValueChanged, true);
            app.StartStopUICameraButton = uibutton(app.Panel_2, 'state');
            app.StartStopUICameraButton.ValueChangedFcn = createCallbackFcn(app, @StartStopUICameraButtonValueChanged, true);
            app.StartStopUICameraButton.Text = 'Start/Stop UI Camera';
            app.StartStopLiveSpectrumButton = uibutton(app.Panel_2, 'state');
            app.StartStopLiveSpectrumButton.ValueChangedFcn = createCallbackFcn(app, @StartStopLiveSpectrumButtonValueChanged, true);
            app.StartStopLiveSpectrumButton.Text = 'Start/Stop Live Spectrum';
            app.SpectrometerGrattingDropDownLabel = uilabel(app.Panel_2);
            app.SpectrometerGrattingDropDownLabel.Text = 'Spectrometer Gratting';
            app.SpectrometerGrattingDropDown = uidropdown(app.Panel_2);
            app.SpectrometerGrattingDropDown.Items = {'1800mm', '1500mm', '150mm'};
            app.SpectrometerGrattingDropDown.ItemsData = [1800 1500 150];
            app.SpectrometerGrattingDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectrometerGrattingDropDownValueChanged, true);
            app.SpectrometerGrattingDropDown.Value = 1800;
            app.TextBoxCameraSettings = uieditfield(app.Panel_2, 'text');
            app.TextBoxCameraSettings.Editable = 'off';
            app.TextBoxCameraSettings.Tooltip = {'Tells the user whether the background has been captured for the day or not'};
            app.StartStopASICameraButton = uibutton(app.Panel_2, 'state');
            app.StartStopASICameraButton.ValueChangedFcn = createCallbackFcn(app, @StartStopASICameraButtonValueChanged, true);
            app.StartStopASICameraButton.Text = 'Start/Stop ASI Camera';
            app.ROI_Percentage = uieditfield(app.CameraSettingsTab, 'numeric');
            app.ROI_Percentage.Limits = [2 50];
            app.ROI_Percentage.RoundFractionalValues = 'on';
            app.ROI_Percentage.ValueChangedFcn = createCallbackFcn(app, @ROI_PercentageValueChanged, true);
            app.ROI_Percentage.Tooltip = {'Percentage (Up and Below ROI Line) to cover'};
            app.ROI_Percentage.Value = 10;
            app.Label_3 = uilabel(app.CameraSettingsTab);
            app.Label_3.Text = '%';
            app.PercentagetoCoverBelowandAboveLabel = uilabel(app.CameraSettingsTab);
            app.PercentagetoCoverBelowandAboveLabel.Text = {'Percentage to Cover '; 'Below and Above'};
            app.ROI_Middle = uieditfield(app.CameraSettingsTab, 'numeric');
            app.ROI_Middle.Limits = [-50 50];
            app.ROI_Middle.RoundFractionalValues = 'on';
            app.ROI_Middle.ValueChangedFcn = createCallbackFcn(app, @ROI_MiddleValueChanged, true);
            app.ROI_Middle.Tooltip = {'Percentage (Up and Below ROI Line) to cover'};
            app.ROI_Middle.Placeholder = 'Defining the middle point';
            app.Label_4 = uilabel(app.CameraSettingsTab);
            app.Label_4.Text = '';
            app.MiddePointLineLabel = uilabel(app.CameraSettingsTab);
            app.MiddePointLineLabel.Text = 'Midde Point Line';
            app.UsercanselectLabel = uilabel(app.CameraSettingsTab);
            app.UsercanselectLabel.Text = {'User can select any value '; 'between 2 and 50.'; 'Ex. 2 represents 2% '; 'of the total screen below '; 'and above or 4 percent '; 'of the whole screen'};
            app.UsercanselectLabel_2 = uilabel(app.CameraSettingsTab);
            app.UsercanselectLabel_2.Text = {'User can select any value '; 'between -50 and 50.'; 'Ex. 0 represents a line of '; 'interest at middle of screen, '; '50 represents the tippity top '; 'and -50 is the bottom of the barrel '; ''};
            app.ListQDTab = uitab(app.TabGroup);
            app.ListQDTab.Title = 'List QD';
            app.ListofQDPanel = uipanel(app.ListQDTab);
            app.ListofQDPanel.Title = 'List of QD';
            app.Bracket2_5 = uilabel(app.ListofQDPanel);
            app.Bracket2_5.Text = '[';
            app.Bracket1_5 = uilabel(app.ListofQDPanel);
            app.Bracket1_5.Text = ']';
            app.ROWLabel_3 = uilabel(app.ListofQDPanel);
            app.ROWLabel_3.Text = 'ROW';
            app.COLUMNLabel_3 = uilabel(app.ListofQDPanel);
            app.COLUMNLabel_3.Text = 'COLUMN';
            app.EditField_Row_FSS = uieditfield(app.ListofQDPanel, 'numeric');
            app.EditField_Row_FSS.Limits = [1 200];
            app.EditField_Row_FSS.RoundFractionalValues = 'on';
            app.EditField_Row_FSS.AllowEmpty = 'on';
            app.EditField_Row_FSS.Tooltip = {'Press ''enter'' to confirm'};
            app.EditField_Row_FSS.Value = [];
            app.EditField_Column_FSS = uieditfield(app.ListofQDPanel, 'numeric');
            app.EditField_Column_FSS.Limits = [1 200];
            app.EditField_Column_FSS.RoundFractionalValues = 'on';
            app.EditField_Column_FSS.AllowEmpty = 'on';
            app.EditField_Column_FSS.Tooltip = {'Press ''enter'' to confirm'};
            app.EditField_Column_FSS.Value = [];
            app.AddToListButton = uibutton(app.ListofQDPanel, 'push');
            app.AddToListButton.ButtonPushedFcn = createCallbackFcn(app, @AddToListButtonPushed, true);
            app.AddToListButton.Text = 'Add To List';
            app.RemoveLastQDButton = uibutton(app.ListofQDPanel, 'push');
            app.RemoveLastQDButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastQDButtonPushed, true);
            app.RemoveLastQDButton.Text = 'Remove Last QD';
            app.ShowListButton = uibutton(app.ListofQDPanel, 'push');
            app.ShowListButton.ButtonPushedFcn = createCallbackFcn(app, @ShowListButtonPushed, true);
            app.ShowListButton.Text = 'Show List';
            app.List_Start_Stop = uibutton(app.ListofQDPanel, 'state');
            app.List_Start_Stop.ValueChangedFcn = createCallbackFcn(app, @List_Start_StopPushed, true);
            app.List_Start_Stop.Text = 'Start/Stop Movement';
            app.SavePhotonCountScanFigureLabel = uilabel(app.ListofQDPanel);
            app.SavePhotonCountScanFigureLabel.Text = {'Save Photon '; 'Count Scan Figure'};
            app.CheckBox_Photon_Scan = uicheckbox(app.ListofQDPanel);
            app.CheckBox_Photon_Scan.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Photon_ScanValueChanged, true);
            app.CheckBox_Photon_Scan.Text = '';
            app.SkipBrighestSpotAroundQDFindingLabel = uilabel(app.ListofQDPanel);
            app.SkipBrighestSpotAroundQDFindingLabel.Text = {'Skip Brighest Spot '; 'Around QD Finding'};
            app.CheckBox_Find_Highest_Peak = uicheckbox(app.ListofQDPanel);
            app.CheckBox_Find_Highest_Peak.Text = '';
            app.SaveRawImgDropDownLabel = uilabel(app.ListofQDPanel);
            app.SaveRawImgDropDownLabel.Text = 'Save Raw Img';
            app.SaveRawImgDropDown = uidropdown(app.ListofQDPanel);
            app.SaveRawImgDropDown.Items = {'Yes', 'No'};
            app.SaveRawImgDropDown.ItemsData = {'Yes', 'No'};
            app.SaveRawImgDropDown.Value = 'Yes';
            app.FSSTotalAngleDropDownLabel = uilabel(app.ListofQDPanel);
            app.FSSTotalAngleDropDownLabel.Text = 'FSS Total Angle';
            app.FSSTotalAngleDropDown = uidropdown(app.ListofQDPanel);
            app.FSSTotalAngleDropDown.Items = {'90', '180', '270', '360'};
            app.FSSTotalAngleDropDown.ItemsData = {'90', '180', '270', '360'};
            app.FSSTotalAngleDropDown.Tooltip = {'The angle chosen is the total amount the HWP rotates by increments of 5'};
            app.FSSTotalAngleDropDown.Value = '180';
            app.ProcessDropDownLabel = uilabel(app.ListofQDPanel);
            app.ProcessDropDownLabel.Text = 'Process';
            app.ProcessDropDown = uidropdown(app.ListofQDPanel);
            app.ProcessDropDown.Items = {'FSS', 'Emission'};
            app.ProcessDropDown.ItemsData = {'FSS', 'Emission'};
            app.ProcessDropDown.Value = 'FSS';
            app.UploadListButton = uibutton(app.ListofQDPanel, 'push');
            app.UploadListButton.ButtonPushedFcn = createCallbackFcn(app, @UploadListButtonPushed, true);
            app.UploadListButton.Text = 'Upload List';
            app.EmptyListButton = uibutton(app.ListofQDPanel, 'push');
            app.EmptyListButton.ButtonPushedFcn = createCallbackFcn(app, @EmptyListButtonPushed, true);
            app.EmptyListButton.Text = 'Empty List';
            app.TextArea_FSS = uitextarea(app.ListQDTab);
            app.TextArea_FSS.Placeholder = 'Update Text Will Appear Here';
            app.Panel_3 = uipanel(app.ListQDTab);
            app.Current_Plot_List = uiimage(app.Panel_3);
            app.CurrentQDPlotLabel_List = uilabel(app.ListQDTab);
            app.CurrentQDPlotLabel_List.Text = 'Current QD Plot ';
            app.CurrentLocationofQDPanel_List = uipanel(app.ListQDTab);
            app.CurrentLocationofQDPanel_List.Title = 'Current Location of QD ';
            app.QD_location_List = uilabel(app.CurrentLocationofQDPanel_List);
            app.QD_location_List.Text = 'Current QD';
            app.UpdateQDButton_List = uibutton(app.CurrentLocationofQDPanel_List, 'push');
            app.UpdateQDButton_List.ButtonPushedFcn = createCallbackFcn(app, @UpdateQDButton_ListPushed, true);
            app.UpdateQDButton_List.Tooltip = {'Press this button to make sure QD is updated when switching between tabs for visual purposes'};
            app.UpdateQDButton_List.Text = 'Update QD';
            app.ANC300Tab = uitab(app.TabGroup);
            app.ANC300Tab.Title = 'ANC300';
            app.ANC300Tab.ButtonDownFcn = createCallbackFcn(app, @ANC300TabButtonDown, true);
            app.Image = uiimage(app.ANC300Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface.png');
            app.AxisLabel = uilabel(app.ANC300Tab);
            app.AxisLabel.Text = 'Axis';
            app.Image2 = uiimage(app.ANC300Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_TextBox.png');
            app.Image2_2 = uiimage(app.ANC300Tab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_TextBox.png');
            app.Image2_3 = uiimage(app.ANC300Tab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_TextBox.png');
            app.FrqHzLabel = uilabel(app.ANC300Tab);
            app.FrqHzLabel.Text = 'Frq (Hz)';
            app.StpVLabel = uilabel(app.ANC300Tab);
            app.StpVLabel.Text = 'Stp (V)';
            app.Image2_4 = uiimage(app.ANC300Tab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_TextBox.png');
            app.ModeLabel = uilabel(app.ANC300Tab);
            app.ModeLabel.Text = 'Mode';
            app.Image2_5 = uiimage(app.ANC300Tab);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_TextBox.png');
            app.XLabel = uilabel(app.ANC300Tab);
            app.XLabel.Text = '1 (X)';
            app.YLabel = uilabel(app.ANC300Tab);
            app.YLabel.Text = '2 (Y)';
            app.Image2_6 = uiimage(app.ANC300Tab);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_TextBox.png');
            app.Y_Freq = uieditfield(app.ANC300Tab, 'numeric');
            app.Y_Freq.Limits = [1 1000];
            app.Y_Freq.RoundFractionalValues = 'on';
            app.Y_Freq.ValueChangedFcn = createCallbackFcn(app, @Y_FreqValueChanged, true);
            app.Y_Freq.Value = 1;
            app.X_Freq = uieditfield(app.ANC300Tab, 'numeric');
            app.X_Freq.Limits = [1 1000];
            app.X_Freq.RoundFractionalValues = 'on';
            app.X_Freq.ValueChangedFcn = createCallbackFcn(app, @X_FreqValueChanged, true);
            app.X_Freq.Value = 1;
            app.Y_Volt = uieditfield(app.ANC300Tab, 'numeric');
            app.Y_Volt.Limits = [0 150];
            app.Y_Volt.ValueChangedFcn = createCallbackFcn(app, @Y_VoltValueChanged, true);
            app.X_Volt = uieditfield(app.ANC300Tab, 'numeric');
            app.X_Volt.Limits = [0 150];
            app.X_Volt.ValueChangedFcn = createCallbackFcn(app, @X_VoltValueChanged, true);
            app.X_Mode = uidropdown(app.ANC300Tab);
            app.X_Mode.Items = {'GND', 'STP'};
            app.X_Mode.ValueChangedFcn = createCallbackFcn(app, @X_ModeValueChanged, true);
            app.X_Mode.Value = 'GND';
            app.Y_Mode = uidropdown(app.ANC300Tab);
            app.Y_Mode.Items = {'GND', 'STP'};
            app.Y_Mode.ValueChangedFcn = createCallbackFcn(app, @Y_ModeValueChanged, true);
            app.Y_Mode.Value = 'GND';
            app.Image8 = uiimage(app.ANC300Tab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_Screw.png');
            app.Image8_2 = uiimage(app.ANC300Tab);
            app.Image8_2.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Piezo_Interface_Screw.png');
            app.OKButton_X = uibutton(app.ANC300Tab, 'push');
            app.OKButton_X.ButtonPushedFcn = createCallbackFcn(app, @OKButton_XPushed, true);
            app.OKButton_X.Tooltip = {'Click to Confirm Setting Changes'};
            app.OKButton_X.Text = 'OK';
            app.OKButton_Y = uibutton(app.ANC300Tab, 'push');
            app.OKButton_Y.ButtonPushedFcn = createCallbackFcn(app, @OKButton_YPushed, true);
            app.OKButton_Y.Tooltip = {'Click to Confirm Setting Changes'};
            app.OKButton_Y.Text = 'OK';
            app.TextArea = uitextarea(app.ANC300Tab);
            app.TextArea.Editable = 'off';
            app.Stop_Everything = uiimage(app.ANC300Tab);
            app.Stop_Everything.ImageClickedFcn = createCallbackFcn(app, @Stop_EverythingImageClicked, true);
            app.Stop_Everything.Tooltip = {'When Pressed, stops all movement in all Axes'};
            app.Stop_Everything.ImageSource = fullfile(pathToMLAPP, 'UI Visuals', 'Stop_sign.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NWQD_Operating_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end